classdef SPT_GUI_v9 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        TrackingDataTab                 matlab.ui.container.Tab
        TrackingOptions                 matlab.ui.control.DropDown
        TrackingperformedwithDropDownLabel  matlab.ui.control.Label
        TrackItDataPanel                matlab.ui.container.Panel
        ImportButton_2                  matlab.ui.control.Button
        DeleteAllButton_4               matlab.ui.control.Button
        AddButton_4                     matlab.ui.control.Button
        DeleteSelectedButton_5          matlab.ui.control.Button
        VideoFilesListBox_2             matlab.ui.control.ListBox
        VideoFilesListBox_2Label        matlab.ui.control.Label
        DeleteAllButton_3               matlab.ui.control.Button
        AddButton_3                     matlab.ui.control.Button
        DeleteSelectedButton_4          matlab.ui.control.Button
        TrackFilesListBox_2             matlab.ui.control.ListBox
        TrackFilesListBox_2Label        matlab.ui.control.Label
        NonimagedframesEditField        matlab.ui.control.NumericEditField
        NonimagedframesEditFieldLabel   matlab.ui.control.Label
        Channel2frequencyEditField      matlab.ui.control.NumericEditField
        Channel2frequencyEditFieldLabel  matlab.ui.control.Label
        channelTimelapseCheckBox        matlab.ui.control.CheckBox
        ImportPreviousSessionButton     matlab.ui.control.Button
        TrackMateDataPanel              matlab.ui.container.Panel
        ImportButton                    matlab.ui.control.Button
        DeleteAllButton_2               matlab.ui.control.Button
        AddButton_2                     matlab.ui.control.Button
        DeleteSelectedButton_2          matlab.ui.control.Button
        VideoFilesListBox               matlab.ui.control.ListBox
        VideoFilesListBoxLabel          matlab.ui.control.Label
        DeleteAllButton                 matlab.ui.control.Button
        AddButton                       matlab.ui.control.Button
        DeleteSelectedButton            matlab.ui.control.Button
        TrackFilesListBox               matlab.ui.control.ListBox
        TrackFilesListBoxLabel          matlab.ui.control.Label
        TrackVisualizationTab           matlab.ui.container.Tab
        Contrast1stChannelLabel         matlab.ui.control.Label
        ResetcontrastButton_ch2         matlab.ui.control.Button
        AdjustcontrastrangeButton_ch2   matlab.ui.control.Button
        DisplayedvideoTextArea          matlab.ui.control.TextArea
        DisplayedvideoTextAreaLabel     matlab.ui.control.Label
        LoadPreviousButton              matlab.ui.control.Button
        ResetcontrastButton             matlab.ui.control.Button
        AdjustcontrastrangeButton       matlab.ui.control.Button
        Contrast2ndChannelLabel         matlab.ui.control.Label
        LoadforanalysisButton           matlab.ui.control.Button
        SavesessionButton               matlab.ui.control.Button
        DataFilteringPanel              matlab.ui.container.Panel
        OnlyspotsfromthefirstframeCheckBox  matlab.ui.control.CheckBox
        ExcludemobilefractionCheckBox   matlab.ui.control.CheckBox
        ExcludeimmobilefractionCheckBox  matlab.ui.control.CheckBox
        FilterbytracklengthCheckBox     matlab.ui.control.CheckBox
        FilterbyROICheckBox             matlab.ui.control.CheckBox
        EraseROIButton                  matlab.ui.control.Button
        SelectROIButton                 matlab.ui.control.Button
        DisplayPanel                    matlab.ui.container.Panel
        Channel2MaskCheckBox            matlab.ui.control.CheckBox
        BrightFieldImageCheckBox        matlab.ui.control.CheckBox
        TrajectoriesCheckBox            matlab.ui.control.CheckBox
        LocalizationsCheckBox           matlab.ui.control.CheckBox
        IDnumberCheckBox                matlab.ui.control.CheckBox
        VideoSelectionListBox           matlab.ui.control.ListBox
        VideoSelectionListBoxLabel      matlab.ui.control.Label
        FrameEditField                  matlab.ui.control.NumericEditField
        FrameEditFieldLabel             matlab.ui.control.Label
        DeleteSelectedButton_3          matlab.ui.control.Button
        VideoPreviewButton              matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        AnalysisTab                     matlab.ui.container.Tab
        ImporttrajectoriesforSTEPButton  matlab.ui.control.Button
        ndChannelMaskAnalysisPanel      matlab.ui.container.Panel
        AnalysisTypeDropDown            matlab.ui.control.DropDown
        AnalysisTypeDropDownLabel       matlab.ui.control.Label
        RegionAnalysisSwitch            matlab.ui.control.ToggleSwitch
        IntensityThrSpinner             matlab.ui.control.Spinner
        IntensityThrSpinnerLabel        matlab.ui.control.Label
        AnalyzeallvideosButton          matlab.ui.control.Button
        ResetThresholdButton            matlab.ui.control.Button
        AnalyzethisvideoButton          matlab.ui.control.Button
        ShowlocalizationsCheckBox       matlab.ui.control.CheckBox
        FrameSlider_Ch2                 matlab.ui.control.Slider
        FrameSliderLabel                matlab.ui.control.Label
        videoname                       matlab.ui.control.EditField
        videoselection                  matlab.ui.control.Spinner
        UIAxes_2                        matlab.ui.control.UIAxes
        SampleCalibrationPanel          matlab.ui.container.Panel
        FramerateanalysisButton         matlab.ui.control.Button
        SpotradiusSpinner               matlab.ui.control.Spinner
        SpotradiusSpinnerLabel          matlab.ui.control.Label
        SpotSNRButton                   matlab.ui.control.Button
        DistanceaftergapButton          matlab.ui.control.Button
        TrajectorylengthButton          matlab.ui.control.Button
        LocalizationPrecisionButton     matlab.ui.control.Button
        PhotobleachingStepsButton       matlab.ui.control.Button
        DiffusionAnalysisPanel          matlab.ui.container.Panel
        GeneralizedDiffusionCoefficientButton  matlab.ui.control.Button
        DiffusionradiusButton           matlab.ui.control.Button
        TurningAngleButton              matlab.ui.control.Button
        TransientConfinementZonesButton  matlab.ui.control.Button
        InstantvelocityButton           matlab.ui.control.Button
        MomentScalingSpectrumButton     matlab.ui.control.Button
        ImmediateDiffusionCoefficientButton  matlab.ui.control.Button
        GeneralFiltersPanel             matlab.ui.container.Panel
        OnlyspotsfromthefirstframeCheckBox_2  matlab.ui.control.CheckBox
        ExcludeimmobilefractionCheckBox_2  matlab.ui.control.CheckBox
        EnablefilteringbyROICheckBox_2  matlab.ui.control.CheckBox
        ExcludemobilefractionCheckBox_2  matlab.ui.control.CheckBox
        FilterbytracklengthCheckBox_2   matlab.ui.control.CheckBox
    end

    
    % Global variables
    properties (Access = public)
        %Import TrackMate
        allfiles % contains the list of selected trackmate files
        allvideos % contains the list of selected video files
        SelectedFile % file selected in the TrackFilesListBox
        SelectedVideo % file selected in the VideoFilesListBox
        %TrackVisualization
        preview_bar %loading bar for the video preview button
        save_session
        showing_video %video selected to be previewed
        fig
        Analysis %structure containing all the results obtained from the Analysis tab
        plot
        Loaded_files % structure containign all the ORIGINAL loaded files, including
        % the track file .csv and the video .tiff
        video_file %video files that are being displayed (after filtering)
    end
    
    properties (Access = public)
      ax;    % video slider axes
      l;     % video slider point
      c_ax % axes of the contrast slider
      c_l % line of the contrast slider
      c2_ax % axes of the contrast slider for the second channel
      c2_l % line of the contrast slider for the second channel
      th_ax % axes of the threshold slider for the second channel (Analysis tab)
      th_l % line of the threshold slider for the second channel (Analysis tab)
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           %% Video Slider
           % Idea taken from https://es.mathworks.com/matlabcentral/answers/484008-range-slider-or-scroll-bar-in-app-designer
           app.ax = axes(...
               app.TrackVisualizationTab,'Color','none','YColor','none','XLim',[1,1000],'YTick',[], ...'
               'XTick',1:10:1000,'TickDir','both','TickLength',[0.01,0.015],'XMinorTick', ...
               'off','Units','pixels','Position',[380 75 600 3]);
           app.ax.XTickLabel = ('');
           % Disable the interactivity & toolbar visibility
           disableDefaultInteractivity(app.ax);
           app.ax.Toolbar.Visible = 'off';
           % Add the sliding point
           app.l = images.roi.Point(app.ax,'Position',[0,1]);
           % Add a listener that will trigger a callback function titled "lMoving" when user
           % moves the ROI endpoints or the line ROI as a whole
           addlistener(app.l,'MovingROI',@(varargin)lMoving(app));
           %% Contrast two knob slider
           app.c_ax = axes(app.TrackVisualizationTab,'Color','none','YColor','none','XLim',[0,100],'YTick',[],...
               'XTick',[0:100:100],'TickDir','both','TickLength',[0.03,0.035],'XMinorTick',...
               'off','Units','pixels','Position',[30,355,300,167]);
           % Disable the interactivity & toolbar visibility
           disableDefaultInteractivity(app.c_ax);
           app.c_ax.Toolbar.Visible = 'off';
           app.c_ax.XRuler.Exponent = 0;
           % Add the sliding line ROI
           app.c_l = images.roi.Line(app.c_ax,'Position',[0.9,0;1.1,0]);
           app.c_l.Visible = 'off';
           % Add a listener that will trigger a callback function titled "Contrast_2KnobSlider" when user
           % moves the ROI endpoints or the line ROI as a whole
           addlistener(app.c_l,'MovingROI',@(varargin)Contrast_2KnobSlider(app));
           %% Contrast two knob slider (2nd channel)
           app.c2_ax = axes(app.TrackVisualizationTab,'Color','none','YColor','none','XLim',[0,255],'YTick',[],...
               'XTick',[0:255:255],'TickDir','both','TickLength',[0.03,0.035],'XMinorTick',...
               'off','Units','pixels','Position',[30,265,300,167]);
           % Disable the interactivity & toolbar visibility
           disableDefaultInteractivity(app.c2_ax);
           app.c2_ax.Toolbar.Visible = 'off';
           app.c2_ax.XRuler.Exponent = 0;
           % Add the sliding line ROI
           app.c2_l = images.roi.Line(app.c2_ax,'Position',[0.9,0;1.1,0]);
           app.c2_l.Position(:,2) = [0;0]; %y position of the knobs
           app.c2_l.Position(:,1) = [0;255];%x position of the knobs
           app.c2_l.Visible = 'off';
           % Add a listener that will trigger a callback function titled "Contrast_ch2_2KnobSlider" when user
           % moves the ROI endpoints or the line ROI as a whole
           addlistener(app.c2_l,'ROIMoved',@(varargin)Contrast_ch2_2KnobSlider(app));
           %% Intensity Threshold (2nd Channel) - Analysis Tab
           app.th_ax = axes(app.AnalysisTab,'Color','none','YColor','none','XLim',[0,255],'YTick',[],...
               'XTick',[0:255:255],'TickDir','both','TickLength',[0.01,0.035],'XMinorTick',...
               'off','Units','pixels','Position',[520,40,472,50]);
           % Disable the interactivity & toolbar visibility
           disableDefaultInteractivity(app.th_ax);
           app.th_ax.Toolbar.Visible = 'off';
           app.th_ax.XRuler.Exponent = 0;
           % Add the sliding line ROI
           app.th_l = images.roi.Line(app.th_ax,'Position',[0.9,0;1.1,0]);
           app.th_l.Position(:,2) = [0;0]; %y position of the knobs
           app.th_l.Position(:,1) = [0;255];%x position of the knobs
           app.th_l.Visible = 'off';
           % Add a listener that will trigger a callback function titled "Contrast_ch2_2KnobSlider" when user
           % moves the ROI endpoints or the line ROI as a whole
           addlistener(app.th_l,'MovingROI',@(varargin)Thresholding(app));
        end

        % Button pushed function: ImportPreviousSessionButton
        function ImportPreviousSessionButtonPushed(app, event)
            %% Check point
            msg = "Remember that in order to properly load your previous session, all your files must be still saved in the same computer location. Is this the case?";
            opts = ["Yes" "No"];
            choice = menu(msg,opts);
            if choice == 2
                return
            end
            %% Update app.save_session and app.Analysis
            [file,path] = uigetfile('*.mat');
            file = string(file);
            path = string(path);
            load(path+file, "save_session");
            app.save_session = save_session;
            %%Clear previous variables
            app.allvideos = [];
            app.allfiles = [];
            app.showing_video = [];
            %% Loading bar
            app.fig = uifigure;
            app.preview_bar = uiprogressdlg(app.fig,'Title','Loading all your videos','Cancelable','on');
            drawnow
            %% Load all files for the visualization and analysis
            f = fields(app.save_session);
            Nfiles = length(f);
            for i = 1:Nfiles
                v = f{i,1};
                if app.save_session.(v).Previewed == 1
                    if app.save_session.(v).Source == 1
                        [video_file] = LoadTrackmateData(app,v);
                    elseif app.save_session.(v).Source == 2
                        [video_file] = LoadTrackitData(app,v);
                    end
                    if exist('video_file') == 0
                        close(app.fig);
                        return
                    end
                    app.video_file = video_file;
                    clear video_file
                    %Save original loaded files in disk
                    dir = what('temp');
                    filename = string(dir.path) + "\" + v + ".mat";
                    var1 = app.Loaded_files;
                    save(filename,'var1','-v7.3')
                    clear var1
                    %Loading bar Cancel check point
                    if app.preview_bar.CancelRequested
                        app.video_file = [];
                        close(app.fig);
                        return
                    end
                end
                if app.save_session.(v).Channel2Type == 1 %single image 2nd channel
                    path = app.save_session.(v).Ch2path;
                    app.Loaded_files.Channel2 = importdata(path);
                    %Loading bar Cancel check point
                    if app.preview_bar.CancelRequested
                        close(app.fig);
                        return
                    end
                end
                %Save original loaded files in disk
                dir = what('temp');
                filename = string(dir.path) + "\" + v + ".mat";
                var1 = app.Loaded_files;
                save(filename,'var1','-v7.3')
                %Loading bar
                app.preview_bar.Value = (i/Nfiles)*0.9;
            end
            %% Display files on Video Selection list box
            list = [];
            for i = 1:Nfiles
                v = f{i,1};
                name = app.save_session.(v).name;
                list{1,i} = char(name);
            end
            app.VideoSelectionListBox.Items = list;
            app.VideoPreviewButton.Enable = 'on';
            app.DeleteSelectedButton_3.Enable = 'on';
            figure(app.UIFigure) % avoids UI to end up in background
            app.DisplayedvideoTextArea.Value{1,1}(:) = [];
            app.DisplayedvideoTextAreaLabel.Enable = 'off';
            %% Visibility control
            app.VideoSelectionListBox.Enable = 'on';
            app.VideoPreviewButton.Enable = 'on';
            app.DeleteSelectedButton_3.Enable = 'on';
            for i = 1:length(app.UIAxes.Children) %everything off
                app.UIAxes.Children(i,1).Visible = 'off';
            end 
            app.FrameEditFieldLabel.Enable = 'off';
            app.FrameEditField.Enable = 'off'; %disables the frame edit field 
            app.Contrast2ndChannelLabel.Enable = 'off';
            app.AdjustcontrastrangeButton.Enable = 'off';
            app.ResetcontrastButton.Enable = 'off';
            app.ax.Toolbar.Visible = 'off';
            app.c_ax.Toolbar.Visible = 'off'; %contrast 2 knob slider
            app.c_l.Visible = 'off';
            app.IDnumberCheckBox.Enable = 'off';
            app.LocalizationsCheckBox.Enable = 'off';
            app.TrajectoriesCheckBox.Enable = 'off';
            app.BrightFieldImageCheckBox.Enable = 'off';
            close(app.fig);
        end

        % Button pushed function: SavesessionButton
        function SavesessionButtonPushed(app, event)
            path = uigetdir;
            save_session = app.save_session;
            save(char(path+"\"+"save_session"),'save_session','-v7.3');
            if ~isempty(app.Analysis)
                analysis = app.Analysis;
                save(char(path+"\"+"Analysis.mat"), "analysis",'-v7.3');
            end
            figure(app.UIFigure) % avoids UI to end up in background
        end

        % Value changed function: TrackFilesListBox
        function TrackFilesListBoxValueChanged(app, event)
            app.SelectedFile = app.TrackFilesListBox.Value;
        end

        % Value changed function: VideoFilesListBox
        function VideoFilesListBoxValueChanged(app, event)
           app.SelectedVideo = app.VideoFilesListBox.Value;
        end

        % Button pushed function: AddButton, AddButton_3
        function AddButtonPushed(app, event)
            %% Addition of localization data files to the TrackFilesListBox. The result is app.allfiles, which can be edited
            % with the addition and deletion of videos. 
            [filename,path] = uigetfile('*.*','All Files (*.*)',...
                'Select files','MultiSelect','on');
            drawnow;
            figure(app.UIFigure) % avoids UI to end up in background
            filename = cellstr(filename);
            path = cellstr(path);
            filePattern = fullfile(path,filename);
            NFiles = sum(cellfun('size',filename,1));
            if NFiles > 1
                filePattern = filePattern.';
            end
            app.allfiles = [app.allfiles;filePattern];
            app.allfiles = unique(app.allfiles,'sorted'); %to avoid uploading a video twice
            %% Change list in the selection listbox
            app.TrackingOptions.ItemsData = 1:length(app.TrackingOptions.Items);
            value = app.TrackingOptions.Value;
            if value == 2
                app.TrackFilesListBox_2.Items = app.allfiles;
            elseif value == 1
                app.TrackFilesListBox.Items = app.allfiles;
            end
        end

        % Button pushed function: DeleteSelectedButton, 
        % ...and 1 other component
        function DeleteSelectedButtonPushed(app, event)
            NFiles = sum(cellfun('size',app.allfiles,1));
            if NFiles == 1
                app.allfiles = {}; 
            else
                idx = ismember(app.allfiles,app.SelectedFile);
                app.allfiles = app.allfiles(idx==0,:);
            end
            %% Change list in the selection listbox
            if app.TrackingOptions.Value == 1
                app.TrackFilesListBox.Items = app.allfiles;
            elseif app.TrackingOptions.Value == 2
                app.TrackFilesListBox_2.Items = app.allfiles;
            end
        end

        % Button pushed function: DeleteAllButton, DeleteAllButton_3
        function DeleteAllButtonPushed(app, event)
            app.allfiles = {};
            %% Change list in the selection listbox
            if app.TrackingOptions.Value == 1
                app.TrackFilesListBox.Items = app.allfiles;
            elseif app.TrackingOptions.Value == 2
                app.TrackFilesListBox_2.Items = app.allfiles;
            end
        end

        % Button pushed function: AddButton_2, AddButton_4
        function AddButton_2Pushed(app, event)
            %% Addition of tiff video files to the VideoFilesListBox. The result is app.allvideos, which can be edited
            % with the addition and deletion of videos. 
            [videoName, videoPath] = uigetfile('*.*','All Files (*.*)',...
                'Select files','MultiSelect','on');
            drawnow;
            figure(app.UIFigure) % avoids UI to end up in background
            videoName = cellstr(videoName);
            videoPath = cellstr(videoPath);
            video_fullName = fullfile(videoPath, videoName);
            NFiles = sum(cellfun('size',video_fullName,1));
            if NFiles > 1
                video_fullName = video_fullName.';
            end
            app.allvideos = [app.allvideos;video_fullName];
            app.allvideos = unique(app.allvideos,'sorted'); %to avoid uploading a video twice
            %% Change list in the selection listbox
            app.TrackingOptions.ItemsData = 1:length(app.TrackingOptions.Items);
            value = app.TrackingOptions.Value;
            if value == 1
                app.VideoFilesListBox.Items = app.allvideos;
            elseif value == 2
                app.VideoFilesListBox_2.Items = app.allvideos;
            end
        end

        % Value changed function: TrackFilesListBox_2
        function TrackFilesListBox_2ValueChanged(app, event)
            app.SelectedFile = app.TrackFilesListBox_2.Value;
        end

        % Value changed function: VideoFilesListBox_2
        function VideoFilesListBox_2ValueChanged(app, event)
            app.SelectedVideo = app.VideoFilesListBox_2.Value;
        end

        % Button pushed function: DeleteAllButton_2, DeleteAllButton_4
        function DeleteAllButton_2Pushed(app, event)
            app.allvideos = {};
            %% Change list in the selection listbox
            if app.TrackingOptions.Value == 1
                app.VideoFilesListBox.Items = app.allvideos;
            elseif app.TrackingOptions.Value == 2
                app.VideoFilesListBox_2.Items = app.allvideos;
            end
        end

        % Button pushed function: DeleteSelectedButton_2, 
        % ...and 1 other component
        function DeleteSelectedButton_2Pushed(app, event)
            NFiles = sum(cellfun('size',app.allvideos,1));
            if NFiles == 1
                app.allvideos = {};
            else
                idx = ismember(app.allvideos,app.SelectedVideo);
                app.allvideos = app.allvideos(idx==0,:);
            end
            %% Change list in the selection listbox
            if app.TrackingOptions.Value == 1
                app.VideoFilesListBox.Items = app.allvideos;
            elseif app.TrackingOptions.Value == 2
                app.VideoFilesListBox_2.Items = app.allvideos;
            end
        end

        % Button pushed function: ImportButton, ImportButton_2
        function ImportButtonPushed(app, event)
            %% In this function, a structure containing all the info to be saved for future analysis (app.save_session)
            % is created. This structure is updated every time the import
            % is refreshed, and extended with the addition of new videos on
            % app.allfiles and app.allvideos. 
            NFiles = sum(cellfun('size',app.allfiles,1)); %number of files in the input listbox
            if isempty(app.save_session) == 0
                oldNFiles = length(fieldnames(app.save_session)); %number of files in the previous input
                paths = cell(oldNFiles,1);
                all_names = fieldnames(app.save_session);
                for i = 1:oldNFiles
                    v = all_names{i,1};
                    paths{i,1} = app.save_session.(v).path{1,2};
                end
            else
                paths = [];
            end
            % for i = 1:length(all_names)
            %     v = strsplit(string(all_names{i,1}),'video_');
            %     all_names{i,1} = v{1,2};
            % end
            for i = 1:NFiles
                newpaths = paths;
                newpaths{end+1} = app.allvideos{i,1};
                newpaths = unique(newpaths); %make sure that the video is not repeted
                if length(newpaths) ~= length(paths) %if the video is not repeated
                    paths = newpaths; %update list of paths
                    % Create structure field
                    video = app.allvideos{i,1};
                    video = strsplit(video,'\');
                    video = strsplit(video{1,end},'.');
                    if length(video)>2 %if there is some other point on the name
                        video = join(video(1,1:end-1),'');
                    end
                    video{1,1} = strrep(video{1,1},"-","_");
                    if strlength(video{1,1})>30
                        video{1,1} = extractBefore(video{1,1}, 30)+"_c" + string(i); %to make sure that it is not too long
                    end
                    v = "video_"+ video{1,1}; %necessary for valid structure field name
                    if ~isempty(app.save_session)
                        if isfield(app.save_session,string(v)) %if the structure field is repeated, but the video is a different one
                            v = v + "_2";
                        end
                    end
                    % Data to be saved on save_session. If the video is uploaded a second time, the previous info is lost. 
                    app.save_session.(v).path{1,1} = app.allfiles{i,1};
                    app.save_session.(v).path{1,2} = app.allvideos{i,1};
                    app.save_session.(v).name = v;
                    app.save_session.(v).Contrast = [0 255];
                    app.save_session.(v).px_size_x = NaN;
                    app.save_session.(v).px_size_y = NaN;
                    app.save_session.(v).FilteredTracks = [];
                    app.save_session.(v).FilteredTracks.Filter_by_ROI = 0;
                    app.save_session.(v).FilteredTracks.Filter_by_length = 0;
                    app.save_session.(v).FilteredTracks.Filter_by_immobile = 0;
                    app.save_session.(v).FilteredTracks.Filter_by_mobile = 0;
                    app.save_session.(v).FilteredTracks.First_frame = 0;
                    app.save_session.(v).Previewed = 0;
                    if app.channelTimelapseCheckBox.Value == 1
                        app.save_session.(v).Time_lapse = 1;
                        app.save_session.(v).Frequency = app.Channel2frequencyEditField.Value;
                        app.save_session.(v).Non_imaged_frame = app.NonimagedframesEditField.Value;
                    else
                        app.save_session.(v).Time_lapse = 0;
                    end
                    if app.TrackingOptions.Value == 1 %import of the tracking data
                        app.save_session.(v).Source = 1; %TrackMate
                    elseif app.TrackingOptions.Value == 2 %Trackit
                        app.save_session.(v).Source = 2;
                    end
                else %still, the timelapse properties are again updated
                    video = fieldnames(app.save_session);
                    v = video{i};
                    if app.channelTimelapseCheckBox.Value == 1
                        app.save_session.(v).Time_lapse = 1;
                        app.save_session.(v).Frequency = app.Channel2frequencyEditField.Value;
                        app.save_session.(v).Non_imaged_frame = app.NonimagedframesEditField.Value;
                    else
                        app.save_session.(v).Time_lapse = 0;
                    end
                end
            end
            %% Display on Video Selection list box
            all_names = fieldnames(app.save_session);
            app.VideoSelectionListBox.Items = all_names;
            app.VideoPreviewButton.Enable = 'on';
            app.DeleteSelectedButton_3.Enable = 'on';
            app.VideoSelectionListBox.Enable = 'on';
        end

        % Value changed function: FrameEditField
        function FrameEditFieldValueChanged(app, event)
            % When the slider is moved, the corresponding frame is shown
            app.l.Position(1,1) = app.FrameEditField.Value;
            lMoving(app);
        end

        % Value changed function: VideoSelectionListBox
        function VideoSelectionListBoxValueChanged(app, event)
            v = strrep(string(app.VideoSelectionListBox.Value),"-","_");
            if app.save_session.(v).Previewed == 1
                app.LoadPreviousButton.Enable = 'on';
            else
                app.LoadPreviousButton.Enable = 'off';
            end
        end

        % Button pushed function: VideoPreviewButton
        function VideoPreviewButtonPushed(app, event)
            %Creating the loading bar (preview_bar)
            app.fig = uifigure;
            app.preview_bar = uiprogressdlg(app.fig,'Title','Processing the video','Cancelable','on');
            drawnow
            v = strrep(string(app.VideoSelectionListBox.Value),"-","_");
            %Loading bar Cancel check point
            if app.preview_bar.CancelRequested
                close(app.fig);
                delete(app.UIAxes.Children(:,1));
                return
            end
            %% Loading all the data for the display (video images, localizations, trajectories...)
            app.showing_video = app.VideoSelectionListBox.Value;
            app.DisplayedvideoTextAreaLabel.Enable = 'on';
            app.DisplayedvideoTextArea.Value = app.showing_video;
            app.Loaded_files = [];
            [app.video_file] = LoadPreview(app);
            %Save original loaded files in disk
            dir = what('temp');
            filename = string(dir.path) + "\" + v + ".mat";
            var1 = app.Loaded_files;
            save(filename,'var1','-v7.3')
            %Loading bar Cancel check point
            if app.preview_bar.CancelRequested
                close(app.fig);
                app.Loaded_files = rmfield(app.Loaded_files,'video_file');
                delete(app.UIAxes.Children(:,1));
                return
            end
            %% Display properties
            app.FrameEditFieldLabel.Enable = 'on';
            app.FrameEditField.Enable = 'on'; %enables the frame edit field
            app.FrameEditField.Value = 1; %sets it to the first frame
            app.Contrast1stChannelLabel.Enable = 'on';
            app.AdjustcontrastrangeButton.Enable = 'on';
            app.ResetcontrastButton.Enable = 'on';
            app.l.Position(1,1) = app.FrameEditField.Value; %syncronizes frame number in the slider and the frame edit field
            app.ax.XLim(1,2) = size(app.video_file{1,3},3); %adjustment of the slider length to the frame number
            app.ax.XTick = 1:((size(app.video_file{1,3},3))/100):(size(app.video_file{1,3},3)); %adjustment of the slider bins
            app.FrameEditField.Limits(1,2) = app.ax.XLim(1,2);
            app.c_ax.Toolbar.Visible = 'on'; %contrast 2 knob slider
            app.c_l.Visible = 'on';
            app.l.Visible = 'on';
            app.DisplayedvideoTextArea.Enable = 'on';
            app.c2_ax.Toolbar.Visible = 'off'; %contrast 2 knob slider
            app.c2_l.Visible = 'off';
            %% Enabling of the different display options
            app.IDnumberCheckBox.Enable = 'on';
            app.LocalizationsCheckBox.Enable = 'on';
            app.TrajectoriesCheckBox.Enable = 'on';
            app.BrightFieldImageCheckBox.Enable = 'on';
            app.BrightFieldImageCheckBox.Value = 0;
            app.Channel2MaskCheckBox.Enable = 'on';
            app.Channel2MaskCheckBox.Value = 0;
            %% Enabling the filtering
            app.FilterbyROICheckBox.Enable = 'on';
            app.FilterbyROICheckBox.Value = 0;
            app.SelectROIButton.Enable = 'off';
            app.EraseROIButton.Enable = 'off';
            app.FilterbytracklengthCheckBox.Enable = 'on';
            app.FilterbytracklengthCheckBox.Value = 0;
            app.OnlyspotsfromthefirstframeCheckBox.Enable = 'on';
            app.OnlyspotsfromthefirstframeCheckBox.Value = 0;
            %% Preview of the first frame: 
            lMoving(app);
            %Loading bar Cancel check point
            if app.preview_bar.CancelRequested
                close(app.fig);
                app.video_file = [];
                delete(app.UIAxes.Children(:,1));
                app.FrameEditFieldLabel.Enable = 'off';
                app.FrameEditField.Enable = 'off';
                app.IDnumberCheckBox.Enable = 'off';
                app.IDnumberCheckBox.Value = 0;
                app.LocalizationsCheckBox.Enable = 'off';
                app.LocalizationsCheckBox.Value = 0;
                app.TrajectoriesCheckBox.Enable = 'off';
                app.TrajectoriesCheckBox.Value = 0;
                app.BrightFieldImageCheckBox.Enable = 'off';
                app.BrightFieldImageCheckBox.Value = 0;
                app.AdjustcontrastrangeButton.Enable = 'off';
                app.ResetcontrastButton.Enable = 'off';
                app.FilterbyROICheckBox.Enable = 'off';
                app.FilterbyROICheckBox.Value = 0;
                app.FilterbytracklengthCheckBox.Enable = 'off';
                app.FilterbytracklengthCheckBox.Value = 0;
                app.SelectROIButton.Enable = 'off';
                app.EraseROIButton.Enable = 'off';
                app.c_l.Visible = 'off';
                app.l.Visible = 'off';
                return
            end
            app.save_session.(v).Previewed = 1;
            app.preview_bar.Value = 1;
            close(app.fig);
        end

        % Button pushed function: LoadPreviousButton
        function LoadPreviousButtonPushed(app, event)
            %% Loading data from save_session and disk to children in UIAxes
            keyboard
            app.showing_video = app.VideoSelectionListBox.Value;
            app.DisplayedvideoTextAreaLabel.Enable = 'on';
            app.DisplayedvideoTextArea.Value = app.showing_video;
            v = strrep(string(app.showing_video),"-","_");
            %Load from disk
            dir = what('temp');
            filename = string(dir.path) + "\" + v + ".mat";
            load(filename);
            app.Loaded_files = var1;
            clear var1
            LoadPrevious(app,v);
            %% Visualization and filters
            % Display properties
            app.FrameEditFieldLabel.Enable = 'on';
            app.FrameEditField.Enable = 'on'; %enables the frame edit field 
            app.FrameEditField.Value = 1; %sets it to the first frame
            app.Contrast2ndChannelLabel.Enable = 'on';
            app.AdjustcontrastrangeButton.Enable = 'on';
            app.ResetcontrastButton.Enable = 'on';
            app.l.Position(1,1) = app.FrameEditField.Value; %syncronizes frame number in the slider and the frame edit field
            app.ax.XLim(1,2) = size(app.video_file{1,3},3); %adjustment of the slider length to the frame number
            app.ax.XTick = 1:((size(app.video_file{1,3},3))/100):(size(app.video_file{1,3},3)); %adjustment of the slider bins
            app.FrameEditField.Limits(1,2) = app.ax.XLim(1,2);
            app.ax.Toolbar.Visible = 'on'; %contrast 2 knob slider
            app.l.Visible = 'on';
            app.c_ax.Toolbar.Visible = 'on'; %contrast 2 knob slider
            app.c_l.Visible = 'on';
            app.DisplayedvideoTextArea.Enable = 'on';
            % Channel 2 mask
            app.c2_ax.Toolbar.Visible = 'off'; %contrast 2 knob slider
            app.c2_l.Visible = 'off';
            % Enabling of the different display options
            app.IDnumberCheckBox.Enable = 'on';
            app.LocalizationsCheckBox.Enable = 'on';
            app.TrajectoriesCheckBox.Enable = 'on';
            app.BrightFieldImageCheckBox.Enable = 'on';
            app.BrightFieldImageCheckBox.Value = 0;
            app.Channel2MaskCheckBox.Enable = 'on';
            app.Channel2MaskCheckBox.Value = 0;
            % Enabling the filtering
            app.FilterbyROICheckBox.Enable = 'on';
            app.FilterbytracklengthCheckBox.Enable = 'on';
            app.OnlyspotsfromthefirstframeCheckBox.Enable = 'on';
            v = strrep(string(app.showing_video),"-","_");
            if app.save_session.(v).FilteredTracks.Filter_by_ROI == 1
                app.FilterbyROICheckBox.Value = 1;
                app.SelectROIButton.Enable = 'on';
                app.EraseROIButton.Enable = 'on';
            else
                app.FilterbyROICheckBox.Value = 0;
                app.SelectROIButton.Enable = 'off';
                app.EraseROIButton.Enable = 'off';
            end
            if app.save_session.(v).FilteredTracks.Filter_by_length == 1
                app.FilterbytracklengthCheckBox.Value = 1;
            else
                app.FilterbytracklengthCheckBox.Value = 0;
            end
            if app.save_session.(v).FilteredTracks.First_frame == 1
                app.OnlyspotsfromthefirstframeCheckBox.Value = 1;
            else
                app.OnlyspotsfromthefirstframeCheckBox.Value = 0;
            end
            if app.save_session.(v).FilteredTracks.Filter_by_immobile == 1
                app.ExcludeimmobilefractionCheckBox.Value = 1;
            else
                app.ExcludeimmobilefractionCheckBox.Value = 0;
            end
            if app.save_session.(v).FilteredTracks.Filter_by_mobile == 1
                app.ExcludemobilefractionCheckBox.Value = 1;
            else
                app.ExcludemobilefractionCheckBox.Value = 0;
            end
            %% Filtering
            R_filtering = app.FilterbyROICheckBox.Value;
            L_filtering = app.FilterbytracklengthCheckBox.Value;
            Imm_filtering = app.ExcludeimmobilefractionCheckBox.Value;
            Mob_filtering = app.ExcludemobilefractionCheckBox.Value;
            First_filtering = app.OnlyspotsfromthefirstframeCheckBox.Value;
            %Variables
            if L_filtering == 1
                min_frame = app.save_session.(v).FilteredTracks.min_frame;
            else
                min_frame = 0;
            end
            if Imm_filtering == 1
                D_imm = app.save_session.(v).FilteredTracks.D_imm;
            else
                D_imm = NaN;
            end
            if Mob_filtering == 1
                D_mob = app.save_session.(v).FilteredTracks.D_mob;
            else
                D_mob = NaN;
            end
            %Filtering
            app.video_file{1,4} = app.Loaded_files.video_file{1,4};
            app.video_file{1,5} = app.Loaded_files.video_file{1,5};
            FilterTracks(app,v,R_filtering,L_filtering,min_frame,...
                Imm_filtering,D_imm,Mob_filtering,D_mob,First_filtering);
            app.video_file{1,4}...
            (app.save_session.(v).FilteredTracks.Filter == 0,:) = NaN;
            app.video_file{1,5}...
            (app.save_session.(v).FilteredTracks.Filter == 0,:) = NaN;
            lMoving(app);
        end

        % Button pushed function: DeleteSelectedButton_3
        function DeleteSelectedButton_3Pushed(app, event)
            v = strrep(string(app.VideoSelectionListBox.Value),"-","_");
            %% Disable video if being displayed
            if isempty(app.showing_video) == 0
                if strrep(string(app.VideoSelectionListBox.Value),"-","_") == string(app.showing_video)
                    app.showing_video = [];
                    app.DisplayedvideoTextArea.Value{1,1}(:) = [];
                    app.DisplayedvideoTextAreaLabel.Enable = 'off';
                end
            end
            %% Disable buttons
            N = sum(cellfun('size',app.VideoSelectionListBox.Items,1));
            NFiles = N(1,1);
            if NFiles == 0
                app.VideoPreviewButton.Enable = 'off';
                app.DeleteSelectedButton_3.Enable = 'off';
                app.VideoPreviewButton.Enable = 'off';
            end
            %% Visualization disable
            app.video_file = [];
            if app.save_session.(v).Previewed == 1
                if isempty(app.UIAxes.Children) == 0
                    delete(app.UIAxes.Children(:,1));
                end
            end
            app.FrameEditFieldLabel.Enable = 'off';
            app.FrameEditField.Enable = 'off';
            app.IDnumberCheckBox.Enable = 'off';
            app.IDnumberCheckBox.Value = 0;
            app.LocalizationsCheckBox.Enable = 'off';
            app.LocalizationsCheckBox.Value = 0;
            app.TrajectoriesCheckBox.Enable = 'off';
            app.TrajectoriesCheckBox.Value = 0;
            app.BrightFieldImageCheckBox.Enable = 'off';
            app.BrightFieldImageCheckBox.Value = 0;
            app.AdjustcontrastrangeButton.Enable = 'off';
            app.ResetcontrastButton.Enable = 'off';
            app.FilterbyROICheckBox.Enable = 'off';
            app.FilterbyROICheckBox.Value = 0;
            app.SelectROIButton.Enable = 'off';
            app.EraseROIButton.Enable = 'off';
            app.FilterbytracklengthCheckBox.Enable = 'off';
            app.FilterbytracklengthCheckBox.Value = 0;
            app.OnlyspotsfromthefirstframeCheckBox.Enable = 'off';
            app.OnlyspotsfromthefirstframeCheckBox.Value = 0;
            app.c_l.Visible = 'off';
            app.l.Visible = 'off';
            %% Deletion the selected video in app.save_session
            app.save_session = rmfield(app.save_session,v);
            list = fields(app.save_session);
            for i = 1:length(list)
                v = strsplit(string(list{i,1}),'video_');
                list{i,1} = v{1,2};
            end
            app.VideoSelectionListBox.Items = list.'; %refreshes the listbox
            [r,~] = size(list);
            if r ~= 0
                VideoSelectionListBoxValueChanged(app,event);
            else 
                app.LoadPreviousButton.Enable = 'off';
                app.DeleteSelectedButton_3.Enable = 'off';
                app.VideoPreviewButton.Enable = 'off';
            end
        end

        % Value changed function: LocalizationsCheckBox
        function LocalizationsCheckBoxValueChanged(app, event)
            lMoving(app);
        end

        % Value changed function: Channel2MaskCheckBox
        function Channel2MaskCheckBoxValueChanged(app, event)
            if app.Channel2MaskCheckBox.Value == 1
                %% Creating the loading bar (preview_bar)
                app.fig = uifigure;
                app.preview_bar = uiprogressdlg(app.fig,'Title','Loading 2nd Channel','Cancelable','on');
                drawnow
                v = strrep(string(app.showing_video),"-","_");
                if isfield(app.Loaded_files,'Channel2') == 0 %if the channel has never been loaded (it is not saved in app.Loaded_files)
                    LoadChannel(app); %loads the 2nd channel image and saves it on app.Loaded_files
                    if app.preview_bar.CancelRequested %loading bar checkpoint
                        app.Channel2MaskCheckBox.Value = 0;
                        close(app.fig);
                        return
                    end
                end
                if isfield(app.Loaded_files,'RGB') == 0
                    app.save_session.(v).Contrast_ch2 = [0 255];
                    ReloadComposite(app); %creates the RGB composite and saves it
                    if app.preview_bar.CancelRequested %loading bar checkpoint
                        app.Channel2MaskCheckBox.Value = 0;
                        app.video_file{1,6} = [];
                        app.Loaded_files.RGB = [];
                        app.Loaded_files = rmfield(app.Loaded_files,'Channel2');
                        close(app.fig);
                        return
                    end
                    %Save original loaded files in disk
                    dir = what('temp');
                    filename = string(dir.path) + "\" + v + ".mat";
                    var1 = app.Loaded_files; %re-save the variable after new data was loaded
                    save(filename,'var1','-v7.3')
                end
                app.c_ax.Toolbar.Visible = 'off'; %contrast 2 knob slider
                app.c_l.Visible = 'off';
                app.c2_ax.Toolbar.Visible = 'on'; %contrast 2 knob slider
                app.c2_l.Visible = 'on';
                app.AdjustcontrastrangeButton.Enable = 'off';
                app.ResetcontrastButton.Enable = 'off';
                app.AdjustcontrastrangeButton_ch2.Enable = 'on';
                app.ResetcontrastButton_ch2.Enable = 'on';
                close(app.fig);
            else
                app.c_ax.Toolbar.Visible = 'on'; %contrast 2 knob slider
                app.c_l.Visible = 'on';
                app.c2_ax.Toolbar.Visible = 'off'; %contrast 2 knob slider
                app.c2_l.Visible = 'off';
                app.AdjustcontrastrangeButton.Enable = 'on';
                app.ResetcontrastButton.Enable = 'on';
                app.AdjustcontrastrangeButton_ch2.Enable = 'off';
                app.ResetcontrastButton_ch2.Enable = 'off';
            end
            lMoving(app);
        end

        % Value changed function: IDnumberCheckBox
        function IDnumberCheckBoxValueChanged(app, event)
            lMoving(app);
        end

        % Value changed function: TrajectoriesCheckBox
        function TrajectoriesCheckBoxValueChanged(app, event)
            lMoving(app);
        end

        % Value changed function: BrightFieldImageCheckBox
        function BrightFieldImageCheckBoxValueChanged(app, event)
            if app.BrightFieldImageCheckBox.Value == 1
                v = strrep(string(app.showing_video),"-","_");
                if isfield(app.Loaded_files,'BrightField') == 0 %if the BF has never been loaded (it is not saved in app.save_session)
                    LoadBrightField(app); %loads the BF image and saves it on app.Loaded_files (and the disk)
                    hold(app.UIAxes,'on')
                    imshow(app.Loaded_files.BrightField(:,:),[],'Parent',app.UIAxes); %we create it
                    chAxes = get(app.UIAxes,'Children');
                    set(app.UIAxes,'Children',[chAxes(2:end);chAxes(1)]); %send BF image to background (last position of children list)
                    hold(app.UIAxes,'off')
                end
            end
            lMoving(app);
            figure(app.UIFigure) % avoids UI to end up in background
        end

        % Value changed function: channelTimelapseCheckBox
        function channelTimelapseCheckBoxValueChanged(app, event)
            if app.channelTimelapseCheckBox.Value == 1
                app.Channel2frequencyEditFieldLabel.Enable = 'on';
                app.Channel2frequencyEditField.Enable = 'on';
                app.NonimagedframesEditFieldLabel.Enable = 'on';
                app.NonimagedframesEditField.Enable = 'on';
            end
        end

        % Button pushed function: AdjustcontrastrangeButton
        function AdjustcontrastrangeButtonPushed(app, event)
            limit = [app.c_l.Position(1,1) app.c_l.Position(2,1)]; %x position of each knob
            app.c_ax.XLim = limit; %limits of the contrast 2 knob slider
            app.c_ax.XTick = limit; %labels at each edge of the axis
        end

        % Button pushed function: ResetcontrastButton
        function ResetcontrastButtonPushed(app, event)
            v = strrep(string(app.VideoSelectionListBox.Value),"-","_");
            bit = (2^8)-1;
            app.save_session.(v).Contrast = [0 bit]; %saved contrast
            app.c_ax.XLim = [0 bit]; %limits of the contrast 2 knob slider
            app.c_l.Position(:,2) = [0;0]; %y position of the knobs
            app.c_l.Position(:,1) = [0;bit];%x position of the knobs
            app.c_ax.XTick = app.c_ax.XLim ; %ticks on the slider
            caxis(app.UIAxes,[app.save_session.(v).Contrast]);
        end

        % Value changed function: FilterbyROICheckBox
        function FilterbyROICheckBoxValueChanged(app, event)
            %% Visibility and enabling control
            R_filtering = app.FilterbyROICheckBox.Value;
            L_filtering = app.FilterbytracklengthCheckBox.Value;
            Imm_filtering = app.ExcludeimmobilefractionCheckBox.Value;
            Mob_filtering = app.ExcludemobilefractionCheckBox.Value;
            First_filtering = app.OnlyspotsfromthefirstframeCheckBox.Value;
            v = strrep(string(app.showing_video),"-","_");
            if R_filtering == 1
                if isfield(app.save_session.(v),'ROI') == 1 %if the ROI was already drawn
                    app.UIAxes.Children((app.save_session.(v).max_text*2)+3,1).Visible = 'on';
                    app.EraseROIButton.Enable = 'on';
                else
                    app.EraseROIButton.Enable = 'off';
                end
                app.SelectROIButton.Enable = 'on';
            else
                app.SelectROIButton.Enable = 'off';
                app.EraseROIButton.Enable = 'off';
                if isfield(app.save_session.(v),'ROI') == 1 %if the ROI was already drawn
                    app.UIAxes.Children((app.save_session.(v).max_text*2)+3,1).Visible = 'off';
                end
            end
            %Variables
            if L_filtering == 1
                min_frame = app.save_session.(v).FilteredTracks.min_frame;
            else
                min_frame = 0;
            end
            if Imm_filtering == 1
                D_imm = app.save_session.(v).FilteredTracks.D_imm;
            else
                D_imm = NaN;
            end
            if Mob_filtering == 1
                D_mob = app.save_session.(v).FilteredTracks.D_mob;
            else
                D_mob = NaN;
            end
            %% Reseting app.video_file to filter again
            app.video_file{1,4} = app.Loaded_files.video_file{1,4};
            app.video_file{1,5} = app.Loaded_files.video_file{1,5};
            FilterTracks(app,v,R_filtering,L_filtering,min_frame,...
                Imm_filtering,D_imm,Mob_filtering,D_mob,First_filtering);
            % Erasing the tracks for display
            app.video_file{1,4}...
            (app.save_session.(v).FilteredTracks.Filter == 0,:) = NaN;
            app.video_file{1,5}...
            (app.save_session.(v).FilteredTracks.Filter == 0,:) = NaN;
            lMoving(app);
        end

        % Button pushed function: SelectROIButton
        function SelectROIButtonPushed(app, event)
            app.SelectROIButton.Enable = 'off';
            app.EraseROIButton.Enable = 'off';
            v = strrep(string(app.showing_video),"-","_");
            if isfield(app.save_session.(v),'ROI') == 1 %if ROI already exists,
                app.save_session.(v) = rmfield(app.save_session.(v),'ROI');
                app.save_session.(v).FilteredTracks = [];
                A = findobj(app.UIAxes.Children, 'Type', 'images.roi.polygon');
                delete(A);
            end
            %% We draw a new ROI
            w = app.Loaded_files.video_file{1,2}.Width;
            h = app.Loaded_files.video_file{1,2}.Height;
            roi = drawpolygon(app.UIAxes,'FaceAlpha',0,'Deletable',0,'InteractionsAllowed','none','LineWidth',1,'Color','r',...
                'DrawingArea',[0,0,w,h]);
            app.save_session.(v).ROI = roi.Position; %and save it in app.save_session
            % In order to properly order the children in UIAxes,
            chAxes = get(app.UIAxes,'Children');
            if isfield(app.Loaded_files,'BrightField') == 0 %if the BF children is not there 
                set(app.UIAxes,'Children',[chAxes(2:end);chAxes(1)]); %we send the polygon to the end of the children list
            else %if BF is the last children
                set(app.UIAxes,'Children',[chAxes(2:end-1);chAxes(1);chAxes(end)]); %we place it righ before the BF image
            end
            %% Visibility control
            app.UIAxes.Children((app.save_session.(v).max_text*2)+3,1).Visible = 'on';
            R_filtering = app.FilterbyROICheckBox.Value;
            L_filtering = app.FilterbytracklengthCheckBox.Value;
            Imm_filtering = app.ExcludeimmobilefractionCheckBox.Value;
            Mob_filtering = app.ExcludemobilefractionCheckBox.Value;
            First_filtering = app.OnlyspotsfromthefirstframeCheckBox.Value;
            %% Erasing the tracks for display
            app.video_file{1,4} = app.Loaded_files.video_file{1,4};
            app.video_file{1,5} = app.Loaded_files.video_file{1,5};
            %Variables
            if L_filtering == 1
                min_frame = app.save_session.(v).FilteredTracks.min_frame;
            else
                min_frame = 0;
            end
            if Imm_filtering == 1
                D_imm = app.save_session.(v).FilteredTracks.D_imm;
            else
                D_imm = NaN;
            end
            if Mob_filtering == 1
                D_mob = app.save_session.(v).FilteredTracks.D_mob;
            else
                D_mob = NaN;
            end
            FilterTracks(app,v,R_filtering,L_filtering,min_frame,...
                Imm_filtering,D_imm,Mob_filtering,D_mob,First_filtering);
            app.video_file{1,4}...
            (app.save_session.(v).FilteredTracks.Filter == 0,:) = NaN;
            app.video_file{1,5}...
            (app.save_session.(v).FilteredTracks.Filter == 0,:) = NaN;
            lMoving(app);
            app.SelectROIButton.Enable = 'on';
            app.EraseROIButton.Enable = 'on';
        end

        % Button pushed function: EraseROIButton
        function EraseROIButtonPushed(app, event)
            v = strrep(string(app.showing_video),"-","_");
            A = findobj(app.UIAxes.Children, 'Type', 'images.roi.polygon');
            delete(A);
            app.save_session.(v) = rmfield(app.save_session.(v),'ROI');
            app.save_session.(v).FilteredTracks = rmfield(app.save_session.(v).FilteredTracks,'ROI');
            app.save_session.(v).FilteredTracks.Filter_by_ROI = 0;
            %% Visibility and enabling control
            app.SelectROIButton.Enable = 'off';
            app.EraseROIButton.Enable = 'off';
            app.FilterbyROICheckBox.Value = 0;
            R_filtering = app.FilterbyROICheckBox.Value;
            L_filtering = app.FilterbytracklengthCheckBox.Value;
            Imm_filtering = app.ExcludeimmobilefractionCheckBox.Value;
            Mob_filtering = app.ExcludemobilefractionCheckBox.Value;
            First_filtering = app.OnlyspotsfromthefirstframeCheckBox.Value;
            %% Erasing the tracks for display
            if L_filtering == 1
                min_frame = app.save_session.(v).FilteredTracks.min_frame;
            else
                min_frame = 0;
            end
            if Imm_filtering == 1
                D_imm = app.save_session.(v).FilteredTracks.D_imm;
            else
                D_imm = NaN;
            end
            if Mob_filtering == 1
                D_mob = app.save_session.(v).FilteredTracks.D_mob;
            else
                D_mob = NaN;
            end
            app.video_file{1,4} = app.Loaded_files.video_file{1,4};
            app.video_file{1,5} = app.Loaded_files.video_file{1,5};
            FilterTracks(app,v,R_filtering,L_filtering,min_frame,...
                Imm_filtering,D_imm,Mob_filtering,D_mob,First_filtering);
            % Erasing the tracks for display
            app.video_file{1,4}...
            (app.save_session.(v).FilteredTracks.Filter == 0,:) = NaN;
            app.video_file{1,5}...
            (app.save_session.(v).FilteredTracks.Filter == 0,:) = NaN;
            lMoving(app);
        end

        % Value changed function: FilterbytracklengthCheckBox
        function FilterbytracklengthCheckBoxValueChanged(app, event)
            R_filtering = app.FilterbyROICheckBox.Value;
            L_filtering = app.FilterbytracklengthCheckBox.Value;
            Imm_filtering = app.ExcludeimmobilefractionCheckBox.Value;
            Mob_filtering = app.ExcludemobilefractionCheckBox.Value;
            First_filtering = app.OnlyspotsfromthefirstframeCheckBox.Value;
            v = strrep(string(app.showing_video),"-","_");
            if L_filtering == 1
                prompt = 'Choose the minimum number of frames';
                min_frame = inputdlg(prompt);
                min_frame = str2double(min_frame);
                if isempty(min_frame) == 1
                    app.FilterbytracklengthCheckBox.Value = 0;
                    return
                end
            else
                min_frame = 0;
            end
            if Imm_filtering == 1
                D_imm = app.save_session.(v).FilteredTracks.D_imm;
            else
                D_imm = NaN;
            end
            if Mob_filtering == 1
                D_mob = app.save_session.(v).FilteredTracks.D_mob;
            else
                D_mob = NaN;
            end
            v = strrep(string(app.showing_video),"-","_");
            app.video_file{1,4} = app.Loaded_files.video_file{1,4};
            app.video_file{1,5} = app.Loaded_files.video_file{1,5};
            FilterTracks(app,v,R_filtering,L_filtering,min_frame,...
                Imm_filtering,D_imm,Mob_filtering,D_mob,First_filtering);
            % Erasing the tracks for display
            app.video_file{1,4}...
            (app.save_session.(v).FilteredTracks.Filter == 0,:) = NaN;
            app.video_file{1,5}...
            (app.save_session.(v).FilteredTracks.Filter == 0,:) = NaN;
            lMoving(app);
        end

        % Value changed function: OnlyspotsfromthefirstframeCheckBox
        function OnlyspotsfromthefirstframeCheckBoxValueChanged(app, event)
            R_filtering = app.FilterbyROICheckBox.Value;
            L_filtering = app.FilterbytracklengthCheckBox.Value;
            Imm_filtering = app.ExcludeimmobilefractionCheckBox.Value;
            Mob_filtering = app.ExcludemobilefractionCheckBox.Value;
            First_filtering = app.OnlyspotsfromthefirstframeCheckBox.Value;
            %% Track filtering
            % Variables
            v = strrep(string(app.showing_video),"-","_");
            if L_filtering == 1
                min_frame = app.save_session.(v).FilteredTracks.min_frame;
            else
                min_frame = 0;
            end
            if Imm_filtering == 1
                D_imm = app.save_session.(v).FilteredTracks.D_imm;
            else
                D_imm = NaN;
            end
            if Mob_filtering == 1
                D_mob = app.save_session.(v).FilteredTracks.D_mob;
            else
                D_mob = NaN;
            end
            % Filtering
            app.video_file{1,4} = app.Loaded_files.video_file{1,4};
            app.video_file{1,5} = app.Loaded_files.video_file{1,5};
            FilterTracks(app,v,R_filtering,L_filtering,min_frame,...
                Imm_filtering,D_imm,Mob_filtering,D_mob,First_filtering);
            app.video_file{1,4}...
            (app.save_session.(v).FilteredTracks.Filter == 0,:) = NaN;
            app.video_file{1,5}...
            (app.save_session.(v).FilteredTracks.Filter == 0,:) = NaN;
            lMoving(app);
        end

        % Value changed function: ExcludeimmobilefractionCheckBox
        function ExcludeimmobilefractionCheckBoxValueChanged(app, event)
            R_filtering = app.FilterbyROICheckBox.Value;
            L_filtering = app.FilterbytracklengthCheckBox.Value;
            Imm_filtering = app.ExcludeimmobilefractionCheckBox.Value;
            Mob_filtering = app.ExcludemobilefractionCheckBox.Value;
            First_filtering = app.OnlyspotsfromthefirstframeCheckBox.Value;
            %% Track filtering
            % Variables
            v = strrep(string(app.showing_video),"-","_");
            if L_filtering == 1
                min_frame = app.save_session.(v).FilteredTracks.min_frame;
            else
                min_frame = 0;
            end
            if Imm_filtering == 1
                prompt = 'Select the minimum diffusion coefficient in um^2/s';
                D_imm = inputdlg(prompt);
                D_imm = str2double(D_imm);
                if isempty(D_imm) == 1 % if the prompt gets canceled
                    app.ExcludeimmobilefractionCheckBox.Value = 0;
                    return
                end
                app.save_session.(v).FilteredTracks.D_imm = D_imm;
            else
                D_imm = NaN;
            end
            if Mob_filtering == 1
                D_mob = app.save_session.(v).FilteredTracks.D_mob;
            else
                D_mob = NaN;
            end
            % Filtering
            app.video_file{1,4} = app.Loaded_files.video_file{1,4};
            app.video_file{1,5} = app.Loaded_files.video_file{1,5};
            FilterTracks(app,v,R_filtering,L_filtering,min_frame,...
                Imm_filtering,D_imm,Mob_filtering,D_mob,First_filtering);
            app.video_file{1,4}...
            (app.save_session.(v).FilteredTracks.Filter == 0,:) = NaN;
            app.video_file{1,5}...
            (app.save_session.(v).FilteredTracks.Filter == 0,:) = NaN;
            lMoving(app);
        end

        % Value changed function: ExcludemobilefractionCheckBox
        function ExcludemobilefractionCheckBoxValueChanged(app, event)
            R_filtering = app.FilterbyROICheckBox.Value;
            L_filtering = app.FilterbytracklengthCheckBox.Value;
            Imm_filtering = app.ExcludeimmobilefractionCheckBox.Value;
            Mob_filtering = app.ExcludemobilefractionCheckBox.Value;
            First_filtering = app.OnlyspotsfromthefirstframeCheckBox.Value;
            %% Track filtering
            % Variables
            v = strrep(string(app.showing_video),"-","_");
            if L_filtering == 1
                min_frame = app.save_session.(v).FilteredTracks.min_frame;
            else
                min_frame = 0;
            end
            if Imm_filtering == 1
                D_imm = app.save_session.(v).FilteredTracks.D_imm;
            else
                D_imm = NaN;
            end
            if Mob_filtering == 1
                prompt = 'Select the maximum diffusion coefficient in um^2/s';
                D_mob = inputdlg(prompt);
                D_mob = str2double(D_mob);
                if isempty(D_mob) == 1 % if the prompt gets canceled
                    app.ExcludemobilefractionCheckBox.Value = 0;
                    return
                end
                app.save_session.(v).FilteredTracks.D_mob = D_mob;
            else
                D_mob = NaN;
            end
            % Filtering
            app.video_file{1,4} = app.Loaded_files.video_file{1,4};
            app.video_file{1,5} = app.Loaded_files.video_file{1,5};
            FilterTracks(app,v,R_filtering,L_filtering,min_frame,...
                Imm_filtering,D_imm,Mob_filtering,D_mob,First_filtering);
            app.video_file{1,4}...
            (app.save_session.(v).FilteredTracks.Filter == 0,:) = NaN;
            app.video_file{1,5}...
            (app.save_session.(v).FilteredTracks.Filter == 0,:) = NaN;
            lMoving(app);
        end

        % Button pushed function: LoadforanalysisButton
        function LoadforanalysisButtonPushed(app, event)
            app.fig = uifigure;
            app.preview_bar = uiprogressdlg(app.fig,'Title','Loading all videos for analysis','Cancelable','on');
            drawnow
            if app.preview_bar.CancelRequested
                close(app.fig);
                return
            end
            LoadForAnalysis(app);
            %% Enable analysis option
            % Enable/Disable General Filtering
            app.FilterbytracklengthCheckBox_2.Enable = 'on';
            app.FilterbytracklengthCheckBox_2.Value = 0;
            app.EnablefilteringbyROICheckBox_2.Enable = 'on';
            app.EnablefilteringbyROICheckBox_2.Value = 0;
            app.OnlyspotsfromthefirstframeCheckBox_2.Enable = 'on';
            app.OnlyspotsfromthefirstframeCheckBox_2.Value = 0;
            app.ExcludemobilefractionCheckBox_2.Enable = 'off';
            app.ExcludemobilefractionCheckBox_2.Value = 0;
            app.ExcludeimmobilefractionCheckBox_2.Enable = 'off';
            app.ExcludeimmobilefractionCheckBox_2.Value = 0;
            app.ExcludemobilefractionCheckBox.Enable = 'off';
            app.ExcludemobilefractionCheckBox.Value = 0;
            app.ExcludeimmobilefractionCheckBox.Enable = 'off';
            app.ExcludeimmobilefractionCheckBox.Value = 0;
            % Enable Analysis
            app.PhotobleachingStepsButton.Enable = 'on';
            app.LocalizationPrecisionButton.Enable = 'on';
            app.ImmediateDiffusionCoefficientButton.Enable = 'on';
            app.InstantvelocityButton.Enable = 'on';
            app.TurningAngleButton.Enable = 'on';
            app.DiffusionradiusButton.Enable = 'on';
            %% Analysis structure
            app.Analysis.Calibration = [];
            app.Analysis.Calibration.Loc_prec = [];
            app.Analysis.Dif_analysis = [];
            app.Analysis.Channel2Mask = [];
            app.Analysis.Channel2Mask.By_video = [];
            app.preview_bar.Value = app.preview_bar.Value + 0.1;
            %% Track Visualization and Analysis Synchronization
            if isempty(app.showing_video) == 1 %if no video has ever been selected
                video = fieldnames(app.save_session);
                v = video{1};
                app.showing_video = app.VideoSelectionListBox.Items{1};
                %Load first video from disk
                dir = what('temp');
                filename = string(dir.path) + "\" + v + ".mat";
                load(filename);
                app.Loaded_files = var1;
                clear var1
                %Update app.video_file
                app.video_file = app.Loaded_files.video_file;
                %Loading all the data for the display (video images, localizations, trajectories...)
                app.DisplayedvideoTextAreaLabel.Enable = 'on';
                app.DisplayedvideoTextArea.Value = app.showing_video;
                [app.video_file] = LoadPreview(app);
                %Display properties
                app.FrameEditFieldLabel.Enable = 'on';
                app.FrameEditField.Enable = 'on'; %enables the frame edit field
                app.FrameEditField.Value = 1; %sets it to the first frame
                app.Contrast1stChannelLabel.Enable = 'on';
                app.AdjustcontrastrangeButton.Enable = 'on';
                app.ResetcontrastButton.Enable = 'on';
                app.l.Position(1,1) = app.FrameEditField.Value; %syncronizes frame number in the slider and the frame edit field
                app.ax.XLim(1,2) = size(app.video_file{1,3},3); %adjustment of the slider length to the frame number
                app.ax.XTick = 1:((size(app.video_file{1,3},3))/100):(size(app.video_file{1,3},3)); %adjustment of the slider bins
                app.FrameEditField.Limits(1,2) = app.ax.XLim(1,2);
                app.ax.Toolbar.Visible = 'on'; %contrast 2 knob slider
                app.l.Visible = 'on';
                app.c_ax.Toolbar.Visible = 'on'; %contrast 2 knob slider
                app.c_l.Visible = 'on';
                app.l.Visible = 'on';
                app.DisplayedvideoTextArea.Enable = 'on';
                app.c2_ax.Toolbar.Visible = 'off'; %contrast 2 knob slider
                app.c2_l.Visible = 'off';
                %Enabling of the different display options
                app.IDnumberCheckBox.Enable = 'on';
                app.LocalizationsCheckBox.Enable = 'on';
                app.TrajectoriesCheckBox.Enable = 'on';
                app.BrightFieldImageCheckBox.Enable = 'on';
                app.BrightFieldImageCheckBox.Value = 0;
                app.Channel2MaskCheckBox.Enable = 'on';
                app.Channel2MaskCheckBox.Value = 0;
                %Enabling the filtering
                app.FilterbyROICheckBox.Enable = 'on';
                app.FilterbyROICheckBox.Value = 0;
                app.SelectROIButton.Enable = 'off';
                app.EraseROIButton.Enable = 'off';
                app.FilterbytracklengthCheckBox.Enable = 'on';
                app.FilterbytracklengthCheckBox.Value = 0;
                app.OnlyspotsfromthefirstframeCheckBox.Enable = 'on';
                app.OnlyspotsfromthefirstframeCheckBox.Value = 0;
                lMoving(app);
            end
            %% 2nd Channel Mask Analysis
            video = fieldnames(app.save_session);
            v = video{1};
            %Load first video from disk
            dir = what('temp');
            filename = string(dir.path) + "\" + v + ".mat";
            load(filename);
            app.Loaded_files = var1;
            clear var1
            %Allow 2nd channel analysis for video 1
            if isfield(app.Loaded_files,'Channel2') == 1
                app.ndChannelMaskAnalysisPanel.Enable = 'on';
                app.videoname.Enable = 'on';
                app.videoselection.Enable = 'on';
                app.RegionAnalysisSwitch.Enable = 'on';
                app.videoselection.Value = 1;
                all_names = fieldnames(app.save_session);
                v = string(all_names{1,1});
                app.videoname.Value = v;
                if length(all_names) > 1
                    app.videoselection.Limits = [1 length(all_names)];
                else
                    app.videoselection.Enable = 'off';
                end
                Load2chAnalysis(app,v);
                %Disable visualization to avoid errors while analyzing
                DisableVisualization(app);
            end
            app.preview_bar.Value = 1; 
            close(app.fig);
            keyboard
        end

        % Value changed function: FilterbytracklengthCheckBox_2
        function FilterbytracklengthCheckBox_2ValueChanged(app, event)
            R_filtering = app.EnablefilteringbyROICheckBox_2.Value;
            L_filtering = app.FilterbytracklengthCheckBox_2.Value;
            Imm_filtering = app.ExcludeimmobilefractionCheckBox_2.Value;
            Mob_filtering = app.ExcludemobilefractionCheckBox_2.Value;
            First_filtering = app.OnlyspotsfromthefirstframeCheckBox_2.Value;
            if L_filtering == 1
                prompt = 'Choose the minimum number of frames';
                min_frame = inputdlg(prompt);
                min_frame = str2double(min_frame);
                if isempty(min_frame) == 1
                    app.FilterbytracklengthCheckBox_2.Value = 0;
                    return
                end
            else
                min_frame = 0;
            end
            video = fieldnames(app.save_session);
            for i = 1:numel(video)
                v = video{i};
                %Load from disk
                dir = what('temp');
                filename = string(dir.path) + "\" + v + ".mat";
                load(filename);
                app.Loaded_files = var1;
                clear var1
                %Filtering
                if Imm_filtering == 1
                    D_imm = app.save_session.(v).FilteredTracks.D_imm;
                else
                    D_imm = NaN;
                end
                if Mob_filtering == 1
                    D_mob = app.save_session.(v).FilteredTracks.D_mob;
                else
                    D_mob = NaN;
                end
                FilterTracks(app,v,R_filtering,L_filtering,min_frame,...
                Imm_filtering,D_imm,Mob_filtering,D_mob,First_filtering);
            end
            if isempty(app.showing_video) == 0 %if no video is being displayed
                LoadPreviousButtonPushed(app, matlab.ui.eventdata.ButtonPushedData)
            end
        end

        % Value changed function: EnablefilteringbyROICheckBox_2
        function EnablefilteringbyROICheckBox_2ValueChanged(app, event)
            R_filtering = app.EnablefilteringbyROICheckBox_2.Value;
            L_filtering = app.FilterbytracklengthCheckBox_2.Value;
            Imm_filtering = app.ExcludeimmobilefractionCheckBox_2.Value;
            Mob_filtering = app.ExcludemobilefractionCheckBox_2.Value;
            First_filtering = app.OnlyspotsfromthefirstframeCheckBox_2.Value;
            video = fieldnames(app.save_session);
            for i = 1:numel(video)
                v = video{i};
                %Load from disk
                dir = what('temp');
                filename = string(dir.path) + "\" + v + ".mat";
                load(filename);
                app.Loaded_files = var1;
                clear var1
                %Filtering
                if L_filtering == 1
                    min_frame = app.save_session.(v).FilteredTracks.min_frame;
                else
                    min_frame = 0;
                end
                if Imm_filtering == 1
                    D_imm = app.save_session.(v).FilteredTracks.D_imm;
                else
                    D_imm = NaN;
                end
                if Mob_filtering == 1
                    D_mob = app.save_session.(v).FilteredTracks.D_mob;
                else
                    D_mob = NaN;
                end
                FilterTracks(app,v,R_filtering,L_filtering,min_frame,...
                Imm_filtering,D_imm,Mob_filtering,D_mob,First_filtering);
            end
            if isempty(app.showing_video) == 0 %if no video is being displayed
                LoadPreviousButtonPushed(app, matlab.ui.eventdata.ButtonPushedData)
            end
        end

        % Value changed function: OnlyspotsfromthefirstframeCheckBox_2
        function OnlyspotsfromthefirstframeCheckBox_2ValueChanged(app, event)
            R_filtering = app.EnablefilteringbyROICheckBox_2.Value;
            L_filtering = app.FilterbytracklengthCheckBox_2.Value;
            Imm_filtering = app.ExcludeimmobilefractionCheckBox_2.Value;
            Mob_filtering = app.ExcludemobilefractionCheckBox_2.Value;
            First_filtering = app.OnlyspotsfromthefirstframeCheckBox_2.Value;
            video = fieldnames(app.save_session);
            for i = 1:numel(video)
                v = video{i};
                %Load from disk
                dir = what('temp');
                filename = string(dir.path) + "\" + v + ".mat";
                load(filename);
                app.Loaded_files = var1;
                clear var1
                %Filtering
                if L_filtering == 1
                    min_frame = app.save_session.(v).FilteredTracks.min_frame;
                else
                    min_frame = 0;
                end
                if Imm_filtering == 1
                    D_imm = app.save_session.(v).FilteredTracks.D_imm;
                else
                    D_imm = NaN;
                end
                if Mob_filtering == 1
                    D_mob = app.save_session.(v).FilteredTracks.D_mob;
                else
                    D_mob = NaN;
                end
                FilterTracks(app,v,R_filtering,L_filtering,min_frame,...
                Imm_filtering,D_imm,Mob_filtering,D_mob,First_filtering);
            end
            if isempty(app.showing_video) == 0 %if one video is being displayed
                LoadPreviousButtonPushed(app, matlab.ui.eventdata.ButtonPushedData)
            end
        end

        % Value changed function: ExcludeimmobilefractionCheckBox_2
        function ExcludeimmobilefractionCheckBox_2ValueChanged(app, event)
            R_filtering = app.EnablefilteringbyROICheckBox_2.Value;
            L_filtering = app.FilterbytracklengthCheckBox_2.Value;
            Imm_filtering = app.ExcludeimmobilefractionCheckBox_2.Value;
            Mob_filtering = app.ExcludemobilefractionCheckBox_2.Value;
            First_filtering = app.OnlyspotsfromthefirstframeCheckBox_2.Value;
            %% Select minimum dif coef
            if Imm_filtering == 1
                prompt = 'Select the minimum diffusion coefficient in \mum^2/s';
                D_imm = inputdlg(prompt);
                D_imm = str2double(D_imm);
                if isempty(D_imm) == 1 % if the prompt gets canceled
                    app.ExcludeimmobilefractionCheckBox_2.Value = 0;
                    return
                end
            end            
            %% Filtering
            video = fieldnames(app.save_session);
            for i = 1:numel(video)
                v = video{i};
                %Load from disk
                dir = what('temp');
                filename = string(dir.path) + "\" + v + ".mat";
                load(filename);
                app.Loaded_files = var1;
                clear var1
                %Filtering
                if L_filtering == 1
                    min_frame = app.save_session.(v).FilteredTracks.min_frame;
                else
                    min_frame = 0;
                end
                if Imm_filtering == 1
                    app.save_session.(v).FilteredTracks.D_imm = D_imm;
                else
                    D_imm = NaN;
                end
                if Mob_filtering == 1
                    D_mob = app.save_session.(v).FilteredTracks.D_mob;
                else
                    D_mob = NaN;
                end
                FilterTracks(app,v,R_filtering,L_filtering,min_frame,...
                Imm_filtering,D_imm,Mob_filtering,D_mob,First_filtering);
            end
            %% Update of Track Visualization tab
            if isempty(app.showing_video) == 0 %if one video is being displayed
                LoadPreviousButtonPushed(app, matlab.ui.eventdata.ButtonPushedData)
            end
        end

        % Value changed function: ExcludemobilefractionCheckBox_2
        function ExcludemobilefractionCheckBox_2ValueChanged(app, event)
            R_filtering = app.EnablefilteringbyROICheckBox_2.Value;
            L_filtering = app.FilterbytracklengthCheckBox_2.Value;
            Imm_filtering = app.ExcludeimmobilefractionCheckBox_2.Value;
            Mob_filtering = app.ExcludemobilefractionCheckBox_2.Value;
            First_filtering = app.OnlyspotsfromthefirstframeCheckBox_2.Value;
            %% Select minimum dif coef
            if Mob_filtering == 1
                prompt = 'Select the maximum diffusion coefficient in um^2/s';
                D_mob = inputdlg(prompt);
                D_mob = str2double(D_mob);
                if isempty(D_mob) == 1 % if the prompt gets canceled
                    app.ExcludemobilefractionCheckBox_2.Value = 0;
                    return
                end
            end
            %% Filtering
            video = fieldnames(app.save_session);
            for i = 1:numel(video)
                v = video{i};
                %Load from disk
                dir = what('temp');
                filename = string(dir.path) + "\" + v + ".mat";
                load(filename);
                app.Loaded_files = var1;
                clear var1
                %Filtering
                if L_filtering == 1
                    min_frame = app.save_session.(v).FilteredTracks.min_frame;
                else
                    min_frame = 0;
                end
                if Imm_filtering == 1
                    D_imm = app.save_session.(v).FilteredTracks.D_imm;
                else
                    D_imm = NaN;
                end
                if Mob_filtering == 1
                    app.save_session.(v).FilteredTracks.D_mob = D_mob;
                else
                    D_mob = NaN;
                end
                FilterTracks(app,v,R_filtering,L_filtering,min_frame,...
                Imm_filtering,D_imm,Mob_filtering,D_mob,First_filtering);
            end
            %% Update of Track Visualization tab
            if isempty(app.showing_video) == 0 %if one video is being displayed
                LoadPreviousButtonPushed(app, matlab.ui.eventdata.ButtonPushedData)
            end
        end

        % Button pushed function: LocalizationPrecisionButton
        function LocalizationPrecisionButtonPushed(app, event)
            app.LocalizationPrecisionButton.BackgroundColor = [1 0 0];
            %% Disable visualization to avoid errors while analyzing
            DisableVisualization(app);
            %% Analysis
            video = fieldnames(app.save_session);
            app.Analysis.Calibration.Loc_prec.By_video = [];
            sd_allvideos = [];
            sigma_allvideos = [];
            FWHM_allvideos = [];
            % Loading bar
            app.fig = uifigure;
            app.preview_bar = uiprogressdlg(app.fig,'Title','Loading','Cancelable','on');
            drawnow
            if app.preview_bar.CancelRequested
                close(app.fig);
                return
            end
            app.preview_bar.Value = 0;
            for i = 1:numel(video)
                v = video{i};
                %Load from disk
                dir = what('temp');
                filename = string(dir.path) + "\" + v + ".mat";
                load(filename);
                app.Loaded_files = var1;
                clear var1
                if i == 1
                    if isnan(app.save_session.(v).px_size_x) 
                        prompt = 'Your data is saved in pixel. Please, introduce the pixel size in [um]';
                        pixel_size = inputdlg(prompt);
                        pixel_size = str2double(pixel_size);
                        app.save_session.(v).px_size_x = pixel_size;
                        app.save_session.(v).px_size_y = pixel_size;
                    else
                        pixel_size = app.save_session.(v).px_size_x;
                    end
                end
                if isempty(pixel_size) == 1 %if the prompt gets canceled
                    app.LocalizationPrecisionButton.BackgroundColor = [0.9608 0.9608 0.9608];
                    return
                end
                %calculate only if there are traj left
                v_x = (app.Loaded_files.video_file{1,4}).*pixel_size;
                v_x(app.save_session.(v).FilteredTracks.Filter == 0,:) = [];
                if isempty(v_x) && all(isnan(v_x(:))) 
                    continue
                end
                % Calculate
                [sd_results,sigma_x,sigma_y,FWHM_x,FWHM_y] = LocalizationPrecision(app,v,pixel_size);
                sd_allvideos = cat (1,sd_allvideos,sd_results);
                pd_y = fitdist(sd_results(:,2),'tlocationscale');
                sigma_i = [sigma_x,sigma_y];
                sigma_allvideos = cat(1,sigma_allvideos,sigma_i);
                FWHM_i = [FWHM_x,FWHM_y];
                FWHM_allvideos = cat(1,FWHM_allvideos,FWHM_i);
                app.Analysis.Calibration.Loc_prec.By_video.(v).Sigma = sigma_i;
                app.Analysis.Calibration.Loc_prec.By_video.(v).FWHM = FWHM_i;
                if app.preview_bar.CancelRequested
                    close(app.fig);
                    return
                end
                app.preview_bar.Value = i/numel(video);
            end
            app.preview_bar.Value = 1;
            close(app.fig);
            %% Gaussian fitting of the deviations from all videos
            [N,c] = hist3(sd_allvideos,'Nbins',[100 100]);
            [h,w]=size(N);
            data=zeros(h*w,3);
            for j=1:h
                for i=1:w
                    data(100*(j-1)+i,1)=c{1,1}(j);
                    data(100*(j-1)+i,2)=c{1,2}(i);
                    data(100*(j-1)+i,3)=N(j,i);
                end
            end
            ftG = fittype('a*exp(-( ((x-b)/c)^2 + ((y-d)/e)^2 ) )','dependent',{'z'},'independent',{'x','y'});
            x = data(:,1);
            y = data(:,2);
            z = data(:,3);
            opts = fitoptions( ftG );
            opts.StartPoint = [max(max(N)), 0.0001, 0.01, 0.0001, 0.01]; %Starting values for the fitting parameters a,b,c,d,e
            fitGt = fit([x, y], z, ftG, opts);
            a = fitGt.a;
            b = fitGt.b;
            c = fitGt.c;
            d = fitGt.d;
            e = fitGt.e;
            sigma_x = c/sqrt(2)*10^3;
            sigma_y = e/sqrt(2)*10^3;
            FWHM_x = 2*sigma_x*sqrt(2*log(2)); %nm Gaussian normal
            FWHM_y = 2*sigma_y*sqrt(2*log(2)); %nm Gaussian normal
            sigma = [sigma_x,sigma_y];
            FWHM = [FWHM_x,FWHM_y];
            app.Analysis.Calibration.Loc_prec.Sigma_General = sigma;
            app.Analysis.Calibration.Loc_prec.FWHM_General = FWHM;
            %% Representation
            figure
            app.plot = axes;
            hist3(app.plot,sd_allvideos,'Nbins',[100 100]);
            app.plot.XLim = [-0.15 0.15];
            app.plot.YLim = [-0.15 0.15];
            app.plot.XLabel.String = 'x [\mum]';
            app.plot.YLabel.String = 'y [\mum]';
            app.plot.ZLabel.String = '# counts';
            app.plot.Title.String = 'Localization precision';
            hold (app.plot,'on')
            x1 = linspace(-0.5,0.5,100)';
            y1 = linspace(-0.5,0.5,100)';
            Z1 = zeros(length(x1),length(y1));
            for k = 1:length(y1)
                for l = 1:length(x1)
                    Z1(k,l)=a*exp(-( ((x1(l)-b)/c)^2 + ((y1(k)-d)/e)^2 ) )+max(max(N))/10;
                end
            end
            surf(x1,y1,Z1,'FaceColor','r','FaceAlpha',0.2,'edgealpha',0.2)
            xlimt = app.plot.XLim(1,1);
            ylimt = app.plot.YLim(1,2)/2;
            zlimt=app.plot.ZLim(1,2);
            txt = {sprintf('\\sigma_x = %.0f nm', sigma(1,1)),sprintf('\\sigma_y = %.0f nm', sigma(1,2)) , sprintf('FWHM_x = %.0f nm', FWHM(1,1)), sprintf('FWHM_y = %.0f nm', FWHM(1,2))};
            text(xlimt,ylimt,zlimt,txt,'Parent',app.plot);
            hold (app.plot,'off')
            app.LocalizationPrecisionButton.BackgroundColor = [0.9608 0.9608 0.9608];
        end

        % Button pushed function: TrajectorylengthButton
        function TrajectorylengthButtonPushed(app, event)
            length_list = [];
            n_traj = [];
            video = fieldnames(app.save_session);
            for i = 1:numel(video)
                v = video{i};
                %Load from disk
                dir = what('temp');
                filename = string(dir.path) + "\" + v + ".mat";
                load(filename);
                app.Loaded_files = var1;
                clear var1
                %Get trajectories
                v_x = (app.Loaded_files.video_file{1,4});
                v_x(app.save_session.(v).FilteredTracks.Filter == 0,:) = [];
                f_rate = app.save_session.(v).f_rate;
                %Calculate length
                log = ~isnan(v_x);
                l = sum(log,2,'omitnan'); %length of each traj in frames
                %l = l * f_rate * 1000; %length in ms
                length_list = [length_list;l];
                m_l = mean(l);
                app.save_session.(v).traj_length = l;
                app.save_session.(v).mean_traj_length = m_l;
                num = sum(log,1,'omitnan')'; %number of loc per frame
                frames = (0:f_rate:((width(v_x)-1)*f_rate))';
                num =[frames,num];
                app.save_session.(v).loc_time = num;
            end
            keyboard
        end

        % Button pushed function: PhotobleachingStepsButton
        function PhotobleachingStepsButtonPushed(app, event)
            app.PhotobleachingStepsButton.BackgroundColor = [1 0 0];
            %% Disable visualization to avoid errors while analyzing
            DisableVisualization(app);
            %% Analysis
            app.Analysis.Calibration.PhotoBleachingSteps = [];
            app.Analysis.Calibration.PhotoBleachingSteps.By_video = [];
            video = fieldnames(app.save_session);
            num_steps = [];
            for i = 1:numel(video)
                v = video{i};
                %Load from disk
                dir = what('temp');
                filename = string(dir.path) + "\" + v + ".mat";
                load(filename);
                app.Loaded_files = var1;
                clear var1
                [num_steps_v] = PhotoBleachingSteps(app,v);
                app.Analysis.Calibration.PhotoBleachingSteps.By_video.(v) = [];
                app.Analysis.Calibration.PhotoBleachingSteps.By_video.(v).n_steps = num_steps_v;
                num_steps = [num_steps;num_steps_v];
                num_steps = [num_steps;num_steps_v];
            end
            app.Analysis.Calibration.PhotoBleachingSteps.n_steps = num_steps;
            app.PhotobleachingStepsButton.BackgroundColor = [0.9608 0.9608 0.9608];
        end

        % Button pushed function: DistanceaftergapButton
        function DistanceaftergapButtonPushed(app, event)
            app.DistanceaftergapButton.BackgroundColor = [1 0 0];
            video = fieldnames(app.save_session);
            Dist_allvideos = [];
            gaps_allvideos = [];
            n_traj_allvideos = [];
            traj_l_allvideos = [];
            for i = 1:numel(video)
                v = video{i};
                %% Load from disk
                dir = what('temp');
                filename = string(dir.path) + "\" + v + ".mat";
                load(filename);
                app.Loaded_files = var1;
                clear var1
                %% Pixel size
                if i == 1
                    if isnan(app.save_session.(v).px_size_x) 
                        prompt = 'Your data is saved in pixel. Please, introduce the pixel size in [um]';
                        pixel_size = inputdlg(prompt);
                        pixel_size = str2double(pixel_size);
                        app.save_session.(v).px_size_x = pixel_size;
                        app.save_session.(v).px_size_y = pixel_size;
                    else
                        pixel_size = app.save_session.(v).px_size_x;
                    end
                end
                if isempty(pixel_size) == 1 %if the prompt gets canceled
                    app.DistanceaftergapButton.BackgroundColor = [0.9608 0.9608 0.9608];
                    return
                end
                %% Get distances
                [Dist_i,gaps_i,n_traj_i,traj_l_i] = Distancefromgap(app,v,pixel_size);
                Dist_i = reshape(Dist_i(~isnan(Dist_i)),[],1);
                Dist_allvideos = [Dist_allvideos;Dist_i];
                gaps_i = reshape(gaps_i(~isnan(gaps_i)),[],1);
                gaps_allvideos = [gaps_allvideos;gaps_i];
                n_traj_allvideos = [n_traj_allvideos;n_traj_i];
                traj_l_i = traj_l_i(~isnan(traj_l_i),1);
                traj_l_allvideos = [traj_l_allvideos;traj_l_i];
            end
            app.DistanceaftergapButton.BackgroundColor = [0.9608 0.9608 0.9608];
            keyboard
        end

        % Button pushed function: SpotSNRButton
        function SpotSNRButtonPushed(app, event)
            video = fieldnames(app.save_session);
            app.Analysis.Calibration.By_video = [];
            %% Creating the loading bar
            app.fig = uifigure;
            app.preview_bar = uiprogressdlg(app.fig,'Title','Calculating the SNR of all spots','Cancelable','on');
            drawnow
            app.preview_bar.Value = 0;
            snr_allvideos = [];
            peak_allvideos = [];
            for i = 1:numel(video)
                v = video{i};
                %% Load from disk
                dir = what('temp');
                filename = string(dir.path) + "\" + v + ".mat";
                load(filename);
                app.Loaded_files = var1;
                clear var1
                [ch1] = LoadNiceImage(app,v);
                %% Get SNR values for each spot
                v_x = (app.Loaded_files.video_file{1,4}(:,:));
                v_y = (app.Loaded_files.video_file{1,5}(:,:));
                app.Loaded_files = [];
                snr = NaN(height(v_x),width(v_x));
                peak = NaN(height(v_x),width(v_x));
                spotRadius = app.SpotradiusSpinner.Value;
                % Apply track filtering
                v_x(app.save_session.(v).FilteredTracks.Filter == 0,:) = 0;
                v_y(app.save_session.(v).FilteredTracks.Filter == 0,:) = 0;
                parfor j = 1:width(v_x) %for each frame
                    % Create "spots" variable
                    spots = [v_x(:,j),v_y(:,j)];
                    % Get the frame
                    originalIm = ch1(:,:,j);
                    % Get SNR values
                    [SNRspots,intPeak] = calc_snr(spots, originalIm, spotRadius);
                    % save it in the right place
                    SNRspots = SNRspots';
                    snr(:,j) = SNRspots;
                    intPeak = intPeak';
                    peak(:,j) = intPeak;
                end
                clear SNRspots intPeak spots originalIm v_x v_y
                %Loading bar cancel
                if app.preview_bar.CancelRequested
                    close(app.fig);
                    return
                end
                %% Save data
                % By video
                app.Analysis.Calibration.By_video.(v).SNR = snr;
                app.Analysis.Calibration.By_video.(v).Peak = peak;
                app.Analysis.Calibration.By_video.(v).median_SNR = median(snr,'all','omitnan');
                app.Analysis.Calibration.By_video.(v).mean_SNR = mean(snr,'all','omitnan');
                app.preview_bar.Value = i/numel(video);
                % All together
                snr = reshape(snr(~isnan(snr)),[],1);
                peak = reshape(peak(~isnan(peak)),[],1);
                snr_allvideos = [snr_allvideos;snr];
                peak_allvideos = [peak_allvideos;peak];
            end
            close(app.fig);
            %% Show the radius around localizations in the last video
            %This is to visualize whether the selected spot radius makes
            %sense
            dir = what('temp');
            filename = string(dir.path) + "\" + v + ".mat";
            load(filename);
            app.Loaded_files = var1;
            clear var1
            v_x = (app.Loaded_files.video_file{1,4}(:,:));
            v_y = (app.Loaded_files.video_file{1,5}(:,:));
            v_x(app.save_session.(v).FilteredTracks.Filter == 0,:) = 0;
            v_y(app.save_session.(v).FilteredTracks.Filter == 0,:) = 0;
            figure
            imshow(imadjust(ch1(:,:,2)));
            hold on
            ind = find(~isnan(v_x(:,2)));
            for j = 1:length(ind)
                a = ind(j);
                x = v_x(a,2);
                y = v_y(a,2);
                th = 0:pi/50:2*pi;
                xunit = spotRadius * cos(th) + x;
                yunit = spotRadius * sin(th) + y;
                plot(xunit, yunit);
            end
            figure
            histogram(snr_allvideos,'Normalization','Probability')
            hold on
            xlabel('SNR')
            ylabel('probability')
            hold off
            figure
            histogram(peak_allvideos,'Normalization','Probability')
            hold on
            xlabel('Peak intensity [grey value]')
            ylabel('probability')
            hold off
            keyboard
        end

        % Button pushed function: FramerateanalysisButton
        function FramerateanalysisButtonPushed(app, event)
            app.FramerateanalysisButton.BackgroundColor = [1 0 0];
            video = fieldnames(app.save_session);
            dxi_allvideos = [];
            dnn_allvideos = [];
            app.Analysis.Calibration.displacements = [];
            %% Previous estimation of the size of the matrix
            w_v = zeros(numel(video),1); % width per video
            for i = 1:numel(video)
                v = video{i};
                w_v(i,1) = app.save_session.(v).num_frames;
            end
            w = max(w_v);
            for i = 1:numel(video)
                v = video{i};
                app.Analysis.Calibration.displacements.(v) = [];
                %% Load from disk
                dir = what('temp');
                filename = string(dir.path) + "\" + v + ".mat";
                load(filename);
                app.Loaded_files = var1;
                clear var1
                %% Pixel size
                if i == 1
                    if isnan(app.save_session.(v).px_size_x)
                        prompt = 'Your data is saved in pixel. Please, introduce the pixel size in [um]';
                        pixel_size = inputdlg(prompt);
                        pixel_size = str2double(pixel_size);
                        app.save_session.(v).px_size_x = pixel_size;
                        app.save_session.(v).px_size_y = pixel_size;
                    else
                        pixel_size = app.save_session.(v).px_size_x;
                    end
                end
                if isempty(pixel_size) == 1 %if the prompt gets canceled
                    app.FramerateanalysisButton.BackgroundColor = [0.9608 0.9608 0.9608];
                    return
                end
                %% Get jumping length and nn distance
                [dxi_i,dnn_i] = Jump(app,v,pixel_size,w);
                app.Analysis.Calibration.displacements.(v).dx = dxi_i;
                app.Analysis.Calibration.displacements.(v).median = median(dxi_i,'all','omitnan');
                dxi_allvideos = [dxi_allvideos;dxi_i];
                dnn_allvideos = [dnn_allvideos;dnn_i];
                clear dxi_i dnn_i
            end
            %% Calculation of the cut-off
            a = reshape(dnn_allvideos(~isnan(dnn_allvideos)),[],1);
            cut = prctile(a,5);
            cut2 = prctile(a,1);
            figure
            [~,edges] = histcounts(dxi_allvideos(~isnan(dxi_allvideos)));
            ax = axes;
            histogram(dnn_allvideos(~isnan(dnn_allvideos)),edges,'Normalization','probability','Parent',ax,'DisplayName',"NN distance within frame")
            hold(ax,'on')
            ylabel("Probability");
            xlabel("Displacement [um]");
            histogram(dxi_allvideos(~isnan(dxi_allvideos)),edges,'Normalization','probability','Parent',ax,'DisplayName',"Displacement trajectory steps")
            legend
            hold(ax,'off')
            f = msgbox("The calculated cut off for a 5% probability of error is " + string(cut) + " [um], and " + string (cut2) + " for a 1% probability of error");
            clear dxi_allvideos dnn_allvideos
            %% Calculation of the retio of rejection and the distance to median
            results = zeros(numel(video),4);
            for i = 1:numel(video)
                v = video{i};
                dxi = app.Analysis.Calibration.displacements.(v).dx;
                included = dxi < cut;
                included = sum(double(included),'all','omitnan');
                notincluded = dxi > cut;
                notincluded = sum(double(notincluded),'all','omitnan');
                ratio = notincluded/(included + notincluded);
                results(i,1) = ratio; %ratio of rejected displacements after cut-off 5%
                included = dxi < cut2;
                included = sum(double(included),'all','omitnan');
                notincluded = dxi > cut2;
                notincluded = sum(double(notincluded),'all','omitnan');
                ratio = notincluded/(included + notincluded);
                results(i,2) = ratio; %ratio of rejected displacements after cut-off 1%
                d2cut = cut - (app.Analysis.Calibration.displacements.(v).median);
                results(i,3) = d2cut; %distance of the median to the 5% cut-off
                d2cut = cut2 - (app.Analysis.Calibration.displacements.(v).median);
                results(i,4) = d2cut; %distance of the median to the 1% cut-off
            end
            app.Analysis.Calibration.displacements.ratios_rejection = results;
            app.FramerateanalysisButton.BackgroundColor = [0.9608 0.9608 0.9608];
            keyboard
        end

        % Button pushed function: ImmediateDiffusionCoefficientButton
        function ImmediateDiffusionCoefficientButtonPushed(app, event)
            app.ImmediateDiffusionCoefficientButton.BackgroundColor = [1 0 0];
            %% Disable visualization to avoid errors while analyzing
            DisableVisualization(app);
            %% Analysis
            video = fieldnames(app.save_session);
            app.Analysis.Dif_analysis.By_video = [];
            Dif_allvideos = [];
            for i = 1:numel(video)
                v = video{i};
                %Load from disk
                dir = what('temp');
                filename = string(dir.path) + "\" + v + ".mat";
                load(filename);
                app.Loaded_files = var1;
                clear var1
                if i == 1
                    if isnan(app.save_session.(v).px_size_x)
                        prompt = 'Your data is saved in pixel units. Please, introduce the pixel size in [um]';
                        pixel_size = inputdlg(prompt);
                        pixel_size = str2double(pixel_size);
                        app.save_session.(v).px_size_x = pixel_size;
                        app.save_session.(v).px_size_y = pixel_size;
                    else
                        pixel_size = app.save_session.(v).px_size_x;
                    end
                end
                [msd_i,Dif_i] = MSD(app,v,pixel_size);
                app.Analysis.Dif_analysis.By_video.(v).ImDif = Dif_i;
                app.Analysis.Dif_analysis.By_video.(v).msd = msd_i;
                Dif_i = Dif_i(~isnan(Dif_i),1);
                Dif_allvideos = [Dif_allvideos;Dif_i];
            end
            Dif_allvideos = Dif_allvideos(~isnan(Dif_allvideos),1);
            app.Analysis.Dif_analysis.ImDif_allvideos = Dif_allvideos;
            figure
            [~,edges] = histcounts(log10(Dif_allvideos));
            histogram(Dif_allvideos,10.^(edges(1,1):0.1:edges(1,end)),'Normalization','probability')
            set(gca, 'xscale','log')
            xlabel('Diff. coef. [\mum^2/s]')
            ylabel('probability')
            hold off
            app.ImmediateDiffusionCoefficientButton.BackgroundColor = [0.9608 0.9608 0.9608];
            app.ExcludemobilefractionCheckBox_2.Enable = 'on';
            app.ExcludeimmobilefractionCheckBox_2.Enable = 'on';
            app.ExcludemobilefractionCheckBox.Enable = 'on';
            app.ExcludeimmobilefractionCheckBox.Enable = 'on';
            app.MomentScalingSpectrumButton.Enable = 'on';
            app.TransientConfinementZonesButton.Enable = 'on';
            app.GeneralizedDiffusionCoefficientButton.Enable = 'on';
            keyboard
        end

        % Button pushed function: MomentScalingSpectrumButton
        function MomentScalingSpectrumButtonPushed(app, event)
            app.MomentScalingSpectrumButton.BackgroundColor = [1 0 0];
            %% Disable visualization to avoid errors while analyzing
            DisableVisualization(app);
            %% Analysis
            video = fieldnames(app.save_session);
            mss_total = [];
            for i = 1:numel(video)
                v = video{i};
                %Load from disk
                dir = what('temp');
                filename = string(dir.path) + "\" + v + ".mat";
                load(filename);
                app.Loaded_files = var1;
                clear var1
                pixel_size = app.save_session.(v).px_size_x;
                [mss_results,~] = MSS(app,v,pixel_size);
                app.Analysis.Dif_analysis.By_video.(v).MSS = mss_results;
                mss_v = [app.Analysis.Dif_analysis.By_video.(v).ImDif,app.Analysis.Dif_analysis.By_video.(v).MSS];
                mss_v(isnan(mss_v(:,2)) == 1,:) = [];
                mss_total = [mss_total;mss_v];
            end
            %mss_total = mss_total(~isnan(mss_total),1);
            app.Analysis.Dif_analysis.mss_allvideos = mss_total;
            %% Plot
            keyboard
            figure
            nexttile(1,[2 2]);
            hold on
            scatter(mss_total(:,1),mss_total(:,2),'x')
            ylim([0 1])
            set(gca, 'XScale', 'log')
            ylim([0 1])
            set(gca, 'XScale', 'log')
            ylabel('S(MSS)')
            nexttile(3,[2 1]);
            edges = linspace(0, 1, 11);
            histogram(mss_total(:,2),'BinEdges',edges,'Normalization','probability')
            xlim([0 1])
            ylabel('probability')
            set ( gca, 'xdir', 'reverse' )
            xline(0.5,'--','Random');
            camroll(-90)
            hold off
            nexttile(7,[1 2]);
            [~,edges] = histcounts(log10(mss_total(:,1)));
            hold on
            histogram(mss_total(:,1),10.^(edges(1,1):0.1:edges(1,end)),'Normalization','probability')
            set(gca, 'xscale','log')
            xlabel('Diff. coef. [\mum^2/s]')
            ylabel('probability')
            hold off
            app.MomentScalingSpectrumButton.BackgroundColor = [0.9608 0.9608 0.9608];
        end

        % Button pushed function: InstantvelocityButton
        function InstantvelocityButtonPushed(app, event)
            %% Disable visualization to avoid errors while analyzing
            DisableVisualization(app);
            %% Analysis
            video = fieldnames(app.save_session);
            v_total = [];
            for i = 1:numel(video)
                v = video{i};
                %Load from disk
                dir = what('temp');
                filename = string(dir.path) + "\" + v + ".mat";
                load(filename);
                app.Loaded_files = var1;
                clear var1
                if i == 1
                    if isnan(app.save_session.(v).px_size_x)
                        prompt = 'Your data is saved in pixel units. Please, introduce the pixel size in [um]';
                        pixel_size = inputdlg(prompt);
                        pixel_size = str2double(pixel_size);
                        app.save_session.(v).px_size_x = pixel_size;
                        app.save_session.(v).px_size_y = pixel_size;
                    else
                        pixel_size = app.save_session.(v).px_size_x;
                    end
                end
                [velocity_i] = InstantVelocity(app,v,pixel_size);
                app.Analysis.Dif_analysis.By_video.(v).InstantV = velocity_i;
                velocity_i = reshape(velocity_i(~isnan(velocity_i)),[],1);
                v_total = [v_total;velocity_i];
            end
            app.Analysis.Dif_analysis.InstantV = v_total;
            figure
            [~,edges] = histcounts(v_total);
            histogram(v_total,edges,'Normalization','probability')
            xlabel('Inst. vel. [\mum/s]')
            ylabel('probability')
            hold off
            keyboard
        end

        % Button pushed function: TransientConfinementZonesButton
        function TransientConfinementZonesButtonPushed(app, event)
            %% Disable visualization to avoid errors while analyzing
            DisableVisualization(app);
            %% Analysis
            video = fieldnames(app.save_session);
            n_stalls_total = [];
            for i = 1:numel(video)
                v = video{i};
                %Load from disk
                dir = what('temp');
                filename = string(dir.path) + "\" + v + ".mat";
                load(filename);
                app.Loaded_files = var1;
                clear var1
                if i == 1
                    if isnan(app.save_session.(v).px_size_x)
                        prompt = 'Your data is saved in pixel units. Please, introduce the pixel size in [um]';
                        pixel_size = inputdlg(prompt);
                        pixel_size = str2double(pixel_size);
                        app.save_session.(v).px_size_x = pixel_size;
                        app.save_session.(v).px_size_y = pixel_size;
                    else
                        pixel_size = app.save_session.(v).px_size_x;
                    end
                end
                [TCZ_video,n_stalls] = TCZ_perMovie(app,v,pixel_size);
                app.Analysis.Dif_analysis.By_video.(v).TCZ_logical = TCZ_video;
                app.Analysis.Dif_analysis.By_video.(v).n_stalls = n_stalls;
                n_stalls_total = [n_stalls_total;n_stalls];
            end
            keyboard
        end

        % Button pushed function: TurningAngleButton
        function TurningAngleButtonPushed(app, event)
            app.TurningAngleButton.BackgroundColor = [1 0 0];
            %% Disable visualization to avoid errors while analyzing
            DisableVisualization(app);
            %% Analysis
            video = fieldnames(app.save_session);
            theta_total = [];
            for i = 1:numel(video)
                v = video{i};
                %Load from disk
                dir = what('temp');
                filename = string(dir.path) + "\" + v + ".mat";
                load(filename);
                app.Loaded_files = var1;
                clear var1
                if i == 1
                    if isnan(app.save_session.(v).px_size_x)
                        prompt = 'Your data is saved in pixel units. Please, introduce the pixel size in [um]';
                        pixel_size = inputdlg(prompt);
                        pixel_size = str2double(pixel_size);
                        app.save_session.(v).px_size_x = pixel_size;
                        app.save_session.(v).px_size_y = pixel_size;
                    else
                        pixel_size = app.save_session.(v).px_size_x;
                    end
                end
                %Calculation
                [theta_i] = TurningAngles(app,v);
                app.Analysis.Dif_analysis.By_video.(v).Angle = theta_i;
                theta_total = [theta_total;theta_i];
            end
            app.Analysis.Dif_analysis.Theta_allvideos = theta_total;
            figure
            polarhistogram(deg2rad(theta_total(:,1)),'Normalization','probability')
            app.TurningAngleButton.BackgroundColor = [0.9608 0.9608 0.9608];
        end

        % Button pushed function: DiffusionradiusButton
        function DiffusionradiusButtonPushed(app, event)
            %% Disable visualization to avoid errors while analyzing
            DisableVisualization(app);
            %% Analysis
            video = fieldnames(app.save_session);
            Rad_allvideos = [];
            for k = 1:numel(video)
                v = video{k};
                %Load from disk
                dir = what('temp');
                filename = string(dir.path) + "\" + v + ".mat";
                load(filename);
                app.Loaded_files = var1;
                clear var1
                if k == 1
                    if isnan(app.save_session.(v).px_size_x)
                        prompt = 'Your data is saved in pixel units. Please, introduce the pixel size in [um]';
                        pixel_size = inputdlg(prompt);
                        pixel_size = str2double(pixel_size);
                        app.save_session.(v).px_size_x = pixel_size;
                        app.save_session.(v).px_size_y = pixel_size;
                    else
                        pixel_size = app.save_session.(v).px_size_x;
                    end
                end
                [Rad_i] = DiffusionRadius(app,v,pixel_size);
                app.Analysis.Dif_analysis.By_video.(v).Dif_radius = Rad_i;
                Rad_i = Rad_i(~isnan(Rad_i),1);
                Rad_allvideos = [Rad_allvideos;Rad_i];
            end
            app.Analysis.Dif_analysis.DifRad_allvideos = Rad_allvideos;
            figure
            [~,edges] = histcounts(Rad_allvideos);
            histogram(Rad_allvideos,edges,'Normalization','probability')
            xlabel('Diffusion radius [\mum]')
            ylabel('probability')
            hold off
            keyboard
        end

        % Button pushed function: GeneralizedDiffusionCoefficientButton
        function GeneralizedDiffusionCoefficientButtonPushed(app, event)
            %% Disable visualization to avoid errors while analyzing
            DisableVisualization(app);
            %% Analysis
            video = fieldnames(app.save_session);
            D_alfa_total = [];
            for i = 1:numel(video)
                v = video{i};
                %Load from disk
                dir = what('temp');
                filename = string(dir.path) + "\" + v + ".mat";
                load(filename);
                app.Loaded_files = var1;
                clear var1
                pixel_size = app.save_session.(v).px_size_x;
                [~,Dif_coef_y0] = MSS(app,v,pixel_size);
                app.Analysis.Dif_analysis.By_video.(v).D_alfa = Dif_coef_y0;
                %mss_v = [app.Analysis.Dif_analysis.By_video.(v).ImDif,app.Analysis.Dif_analysis.By_video.(v).MSS];
                %mss_v(isnan(mss_v(:,2)) == 1,:) = [];
                D_alfa_total = [D_alfa_total;Dif_coef_y0];
            end
            D_alfa_total = D_alfa_total(~isnan(D_alfa_total),1);
            app.Analysis.Dif_analysis.D_alfa_allvideos = D_alfa_total;
            %% Plot
            figure
            [~,edges] = histcounts(log10(D_alfa_total));
            histogram(D_alfa_total,10.^(edges(1,1):0.1:edges(1,end)),'Normalization','probability')
            set(gca, 'xscale','log')
            xlabel('Diff. coef. [\mum^2/s^\alpha]')
            ylabel('probability')
            hold off
            keyboard
        end

        % Button pushed function: AdjustcontrastrangeButton_ch2
        function AdjustcontrastrangeButton_ch2Pushed(app, event)
            limit = [app.c2_l.Position(1,1) app.c2_l.Position(2,1)]; %x position of each knob
            app.c2_ax.XLim = limit; %limits of the contrast 2 knob slider
            app.c2_ax.XTick = limit; %labels at each edge of the axis
        end

        % Button pushed function: ResetcontrastButton_ch2
        function ResetcontrastButton_ch2Pushed(app, event)
            %% Creating the loading bar
            app.fig = uifigure;
            app.preview_bar = uiprogressdlg(app.fig,'Title','Changing contrast of the 2nd Channel','Cancelable','on');
            drawnow
            %% Reset contrast limits
            v = "video_" + strrep(string(app.VideoSelectionListBox.Value),"-","_");
            old_contrast1 = app.save_session.(v).Contrast_ch2(1,1);
            old_contrast2 = app.save_session.(v).Contrast_ch2(1,2);
            bit = (2^8)-1;
            app.save_session.(v).Contrast_ch2 = [0 bit]; %saved contrast
            app.c2_ax.XLim = [0 bit]; %limits of the contrast 2 knob slider
            app.preview_bar.Value = 0.1;
            %% Creating the composite again
            app.c2_l.Position(:,2) = [0;0]; %y position of the knobs
            app.c2_l.Position(:,1) = [0;bit];%x position of the knobs
            app.c2_ax.XTick = app.c2_ax.XLim ; %ticks on the slider
            app.preview_bar.Value = 0.2;
            ReloadComposite(app);
            if app.preview_bar.CancelRequested
                app.c2_l.Position(1,1) = old_contrast1;
                app.c2_l.Position(2,1) = old_contrast2;
                app.save_session.(v).Contrast_ch2(1,1) = old_contrast1;
                app.save_session.(v).Contrast_ch2(1,2) = old_contrast2;
                close(app.fig);
                return
            end
            lMoving(app);
            app.preview_bar.Value = 1;
            close(app.fig);
        end

        % Value changing function: FrameSlider_Ch2
        function FrameSlider_Ch2ValueChanging(app, event)
            app.FrameSlider_Ch2.Value = round(app.FrameSlider_Ch2.Value);
            v = app.videoname.Value;
            if app.Analysis.Channel2Mask.By_video.(v).ResetButton == 1
                Thresholding(app);
            elseif app.Analysis.Channel2Mask.By_video.(v).ResetButton == 0
                MaskSegmentation(app);
            end
        end

        % Value changed function: videoselection
        function videoselectionValueChanged(app, event)
            all_names = fieldnames(app.save_session);
            i = app.videoselection.Value;
            v = string(all_names{i,1});
            app.videoname.Value = v;
            %% Load from disk
            dir = what('temp');
            filename = string(dir.path) + "\" + v + ".mat";
            load(filename);
            app.Loaded_files = var1;
            clear var1
            %% Load 2 Channel analysis
            if isfield(app.Loaded_files,'Channel2') == 1
                Load2chAnalysis(app,v);
            else
                app.FrameSlider_Ch2.Enable = 'off';
                app.th_ax.Toolbar.Visible = 'off';
                app.th_l.Visible = 'off';
                app.AnalyzethisvideoButton.Enable = 'off';
                app.UIAxes_2.Children.CData = [];
            end
%             %% Synchronization with Track Visualization Tab
%             app.VideoSelectionListBox.Value = app.VideoSelectionListBox.Items{i};
%             LoadPreviousButtonPushed(app,event);
        end

        % Button pushed function: AnalyzethisvideoButton
        function AnalyzethisvideoButtonPushed(app, event)
            %% Disable visualization to avoid errors while analyzing
            DisableVisualization(app);
            %% Filtering parameters
            R_filtering = app.EnablefilteringbyROICheckBox_2.Value;
            L_filtering = app.FilterbytracklengthCheckBox_2.Value;
            Imm_filtering = app.ExcludeimmobilefractionCheckBox_2.Value;
            Mob_filtering = app.ExcludemobilefractionCheckBox_2.Value;
            First_filtering = app.OnlyspotsfromthefirstframeCheckBox_2.Value;
            %% Thresholding and image segmentation
            v = app.videoname.Value;
            %Load from disk
            dir = what('temp');
            filename = string(dir.path) + "\" + v + ".mat";
            load(filename);
            app.Loaded_files = var1;
            clear var1
            if isequal(app.RegionAnalysisSwitch.Value ,'Simple')
                ThresholdingAll_simple(app,R_filtering)
            else
                ThresholdingAll(app,R_filtering)
            end
            if ~isfield(app.Analysis.Channel2Mask.By_video.(v),'Overlay')
                return
            end
            %% Plotting image segmentation
            MaskSegmentation(app);
            %% Enable/Disable
            app.ShowlocalizationsCheckBox.Enable = 'on';
            app.ResetThresholdButton.Enable = 'on';
            app.th_ax.Toolbar.Visible = 'off';
            app.th_l.Visible = 'off';
            app.Analysis.Channel2Mask.By_video.(v).ResetButton = 0;
            app.ShowlocalizationsCheckBox.Enable = 'on';
            app.AnalyzeallvideosButton.Enable = 'on';
            app.AnalysisTypeDropDown.Enable = 'on';
            app.AnalysisTypeDropDownLabel.Enable = 'on';
        end

        % Value changed function: ShowlocalizationsCheckBox
        function ShowlocalizationsCheckBoxValueChanged(app, event)
            MaskSegmentation(app);
        end

        % Button pushed function: ResetThresholdButton
        function ResetThresholdButtonPushed(app, event)
            %% Enable threshold sliders
            app.th_ax.Toolbar.Visible = 'on';
            app.th_l.Visible = 'on';
            v = app.videoname.Value;
            app.Analysis.Channel2Mask.By_video.(v).ResetButton = 1;
            app.ShowlocalizationsCheckBox.Enable = 'off';
            %% Show the threshold selector
            Thresholding(app);
        end

        % Button pushed function: AnalyzeallvideosButton
        function AnalyzeallvideosButtonPushed(app, event)
            %% Creating the loading bar
            app.fig = uifigure;
            app.preview_bar = uiprogressdlg(app.fig,'Title','Analyzing all videos','Cancelable','off');
            drawnow
            %% Disable visualization to avoid errors while analyzing
            DisableVisualization(app);
            %% Parameters to classify
            %It finds which dif parameters where calculated and therfore can be
            %classified by region. 
            if isfield(app.Analysis.Dif_analysis,'ImDif_allvideos') == 1
                imdif_yes = 1;
            else
                imdif_yes = 0;
            end
            if isfield(app.Analysis.Dif_analysis,'mss_allvideos') == 1
                mss_yes = 1;
            else
                mss_yes = 0;
            end
            if isfield(app.Analysis.Dif_analysis,'InstantV') == 1
                instv_yes = 1;
            else
                instv_yes = 0;
            end
            if isfield(app.Analysis.Dif_analysis,'Theta_allvideos') == 1
                theta_yes = 1;
            else
                theta_yes = 0;
            end
            if isfield(app.Analysis.Dif_analysis,'DifRad_allvideos') == 1
                rad_yes = 1;
            else
                rad_yes = 0;
            end
            video = fieldnames(app.save_session);
            %% Future calculated parameters
            dif_out_allvideos = [];
            dif_in_allvideos = [];
            mss_in_allvideos = [];
            mss_out_allvideos = [];
            vel_in_allvideos = [];
            vel_out_allvideos = [];
            dif_far_allvideos = [];
            dif_close_allvideos = [];
            mss_far_allvideos = [];
            mss_close_allvideos = [];
            vel_far_allvideos = [];
            vel_close_allvideos = [];
            rad_in_allvideos = [];
            rad_out_allvideos = [];
            theta_in_allvideos = [];
            theta_out_allvideos = [];
            rad_close_allvideos = [];
            rad_far_allvideos = [];
            theta_close_allvideos = [];
            theta_far_allvideos = [];
            n_traj_in_allvideos = 0;
            n_traj_out_allvideos = 0;
            n_traj_close_allvideos = 0;
            n_traj_far_allvideos = 0;
            n_stalls_out_allvideos = 0;
            n_stalls_in_allvideos = 0;
            ratio_in_TCZ = zeros(numel(video),1);
            n_stalls_far_allvideos = 0;
            n_stalls_close_allvideos = 0;
            ratio_close_TCZ = zeros(numel(video),1);
            last_index = [];
            %% Classification
             for i = 1:numel(video)
                v = video{i};
                %Load from disk
                dir = what('temp');
                filename = string(dir.path) + "\" + v + ".mat";
                load(filename);
                app.Loaded_files = var1;
                clear var1
                if isfield(app.Analysis.Channel2Mask.By_video.(v),'Boundaries') == 1
                    %% Classification IN vs OUT
                    if isequal(app.AnalysisTypeDropDown.Value ,'In vs. Out')
                        app.AnalysisTypeDropDown.Value = 'In vs. Out';
                        [dif_out,dif_in,mss_out,mss_in,vel_in,vel_out,traj_in,traj_out,...
                            rad_in,rad_out,theta_in,theta_out] = RegionAnalysis(app,v,imdif_yes,...
                            mss_yes,instv_yes,theta_yes,rad_yes);
                        dif_out_allvideos = [dif_out_allvideos;dif_out];
                        dif_in_allvideos = [dif_in_allvideos;dif_in];
                        mss_in_allvideos = [mss_in_allvideos;mss_in];
                        mss_out_allvideos = [mss_out_allvideos;mss_out];
                        vel_in = reshape(vel_in(~isnan(vel_in)),[],1);
                        vel_in_allvideos = [vel_in_allvideos;vel_in];
                        vel_out = reshape(vel_out(~isnan(vel_out)),[],1);
                        vel_out_allvideos = [vel_out_allvideos;vel_out];
                        rad_in_allvideos = [rad_in_allvideos;rad_in];
                        rad_out_allvideos = [rad_out_allvideos;rad_out];
                        theta_in_allvideos = [theta_in_allvideos;theta_in];
                        theta_out_allvideos = [theta_out_allvideos;theta_out];
                        n_traj_in_allvideos = n_traj_in_allvideos + traj_in;
                        n_traj_out_allvideos = n_traj_out_allvideos + traj_out;
                        if isfield(app.Analysis.Dif_analysis.By_video.(v),'n_stalls') == 1
                            [n_stalls_in,n_stalls_out] = TCZ_Region_inside(v,app);
                            n_stalls_out = sum(n_stalls_out,'all');
                            n_stalls_out_allvideos = n_stalls_out_allvideos + n_stalls_out;
                            n_stalls_in = sum(n_stalls_in,'all');
                            n_stalls_in_allvideos = n_stalls_in_allvideos + n_stalls_in;
                            ratio_in_TCZ(i,1) = n_stalls_in/(n_stalls_out+n_stalls_in);
                        end
                    end
                    %% Classification CLOSE vs FAR
                    if isequal(app.AnalysisTypeDropDown.Value ,'Close vs. Far')
                        [dif_far,dif_close,mss_far,mss_close,vel_far,vel_close,traj_close,traj_far,...
                            rad_close,rad_far,theta_close,theta_far] = Distance2Boundaries(app,v,imdif_yes,...
                            mss_yes,instv_yes,theta_yes,rad_yes);
                        dif_close_allvideos = [dif_close_allvideos;dif_close];
                        dif_far_allvideos = [dif_far_allvideos;dif_far];
                        mss_close_allvideos = [mss_close_allvideos;mss_close];
                        mss_far_allvideos = [mss_far_allvideos;mss_far];
                        vel_close = reshape(vel_close(~isnan(vel_close)),[],1);
                        vel_close_allvideos = [vel_close_allvideos;vel_close];
                        vel_far = reshape(vel_far(~isnan(vel_far)),[],1);
                        vel_far_allvideos = [vel_far_allvideos;vel_far];
                        rad_close_allvideos = [rad_close_allvideos;rad_close];
                        rad_far_allvideos = [rad_far_allvideos;rad_far];
                        theta_close_allvideos = [theta_close_allvideos;theta_close];
                        theta_far_allvideos = [theta_far_allvideos;theta_far];
                        n_traj_close_allvideos = n_traj_close_allvideos + traj_close;
                        n_traj_far_allvideos = n_traj_far_allvideos + traj_far;
                        if isfield(app.Analysis.Dif_analysis.By_video.(v),'n_stalls') == 1
                            [n_stalls_c,n_stalls_f] = TCZ_Region_close(v,app);
                            n_stalls_f = sum(n_stalls_f,'all');
                            n_stalls_far_allvideos = n_stalls_far_allvideos + n_stalls_f;
                            n_stalls_c = sum(n_stalls_c,'all');
                            n_stalls_close_allvideos = n_stalls_close_allvideos + n_stalls_c;
                            ratio_close_TCZ(i,1) = n_stalls_c/(n_stalls_f+n_stalls_c);
                        end
                    end
                    %% Last frame distance
                    if isequal(app.AnalysisTypeDropDown.Value ,'Distance of last frame')
                        distance = app.Analysis.Channel2Mask.By_video.(v).Distance2Boundaries;
                        pixel_size = app.save_session.(v).px_size_x;
                        distance = distance * pixel_size;
                        if isfield(app.Analysis.Channel2Mask.By_video.(v),'group_inside')
                            inside = app.Analysis.Channel2Mask.By_video.(v).group_inside;
                            inside = logical(inside);
                            distance(inside) = distance(inside)*(-1);
                        end
                        [t,~] = size(distance);
                        parfor j = 1:t
                            distance_j = distance(j,:);
                            if sum(distance_j,'all','omitnan') ~= 0
                                last_value = find(~isnan(distance_j), 1, 'last');
                                last_index = [last_index;distance_j(1,last_value)];
                            end
                        end
                    end
                end
                app.preview_bar.Value = (1/numel(video));
            end
            %% Save data
            if isequal(app.AnalysisTypeDropDown.Value ,'Distance of last frame')
                app.Analysis.Channel2Mask.Last_distance2Mask = last_index;
            end
            if imdif_yes == 1
                if isequal(app.AnalysisTypeDropDown.Value ,'In vs. Out')
                    app.Analysis.Channel2Mask.Dif_inside = dif_in_allvideos;
                    app.Analysis.Channel2Mask.Dif_outside = dif_out_allvideos;
                elseif isequal(app.AnalysisTypeDropDown.Value ,'Close vs. Far')
                    app.Analysis.Channel2Mask.Dif_close = dif_close_allvideos;
                    app.Analysis.Channel2Mask.Dif_far = dif_far_allvideos;
                end
            end
            if mss_yes == 1
                if isequal(app.AnalysisTypeDropDown.Value ,'In vs. Out')
                    app.Analysis.Channel2Mask.mss_inside = mss_in_allvideos;
                    app.Analysis.Channel2Mask.mss_outside = mss_out_allvideos;
                elseif isequal(app.AnalysisTypeDropDown.Value ,'Close vs. Far')
                    app.Analysis.Channel2Mask.mss_close = mss_close_allvideos;
                    app.Analysis.Channel2Mask.mss_far = mss_far_allvideos;
                end
            end
            if instv_yes == 1
                if isequal(app.AnalysisTypeDropDown.Value ,'In vs. Out')
                    app.Analysis.Channel2Mask.Vel_inside = vel_in_allvideos;
                    app.Analysis.Channel2Mask.Vel_outside = vel_out_allvideos;
                elseif isequal(app.AnalysisTypeDropDown.Value ,'Close vs. Far')
                    app.Analysis.Channel2Mask.Vel_close = vel_close_allvideos;
                    app.Analysis.Channel2Mask.Vel_far = vel_far_allvideos;
                end
            end
            if isfield(app.Analysis.Dif_analysis.By_video.(v),'n_stalls') == 1
                if isequal(app.AnalysisTypeDropDown.Value ,'In vs. Out')
                    app.Analysis.Channel2Mask.N_stalls_out = n_stalls_out_allvideos;
                    app.Analysis.Channel2Mask.N_stalls_in = n_stalls_in_allvideos;
                    app.Analysis.Channel2Mask.ratio_in_TCZ = ratio_in_TCZ;
                elseif isequal(app.AnalysisTypeDropDown.Value ,'Close vs. Far')
                    app.Analysis.Channel2Mask.N_stalls_far = n_stalls_far_allvideos;
                    app.Analysis.Channel2Mask.N_stalls_close = n_stalls_close_allvideos;
                    app.Analysis.Channel2Mask.ratio_close_TCZ = ratio_close_TCZ;
                end
            end
            app.preview_bar.Value = 1;
            close(app.fig);
            keyboard
        end

        % Value changed function: IntensityThrSpinner
        function IntensityThrSpinnerValueChanged(app, event)
            AnalyzethisvideoButtonPushed(app, event);
        end

        % Value changed function: RegionAnalysisSwitch
        function RegionAnalysisSwitchValueChanged(app, event)
            if isequal(app.RegionAnalysisSwitch.Value ,'Simple')
                app.IntensityThrSpinner.Enable = 'off';
            elseif isequal(app.RegionAnalysisSwitch.Value ,'Complex')
                app.IntensityThrSpinner.Enable = 'on';
            end
        end

        % Value changed function: Channel2frequencyEditField
        function Channel2frequencyEditFieldValueChanged(app, event)
            value = app.Channel2frequencyEditField.Value;
        end

        % Value changed function: TrackingOptions
        function TrackingOptionsValueChanged(app, event)
            % To get a scalar for each option:
            app.TrackingOptions.ItemsData = 1:length(app.TrackingOptions.Items);
            value = app.TrackingOptions.Value;
            if value == 2
                app.allvideos = {};
                app.allfiles = {};
                app.TrackFilesListBox.Items = app.allfiles;
                app.VideoFilesListBox.Items = app.allvideos; %erase list in the TrackMate panel
                app.TrackMateDataPanel.Enable = 'off';
                app.TrackItDataPanel.Enable = 'on';
            elseif value == 1
                app.allvideos = {};
                app.allfiles = {};
                app.TrackFilesListBox_2.Items = app.allfiles;
                app.VideoFilesListBox_2.Items = app.allvideos; %erase list int the Trackit panel
                app.TrackMateDataPanel.Enable = 'on';
                app.TrackItDataPanel.Enable = 'off';
            end
        end

        % Button pushed function: ImporttrajectoriesforSTEPButton
        function Import4STEP(app, event)
            path = uigetdir;
            video = fieldnames(app.save_session);
            for i = 1:numel(video)
                v = video{i};
                %% Data and video import
                %Get loaded files from disk
                dir = what('temp');
                filename = string(dir.path) + "\" + v + ".mat";
                load(filename);
                app.Loaded_files = var1;
                clear var1
                v_x = (app.Loaded_files.video_file{1,4});
                v_y = (app.Loaded_files.video_file{1,5});
                distance = app.Analysis.Channel2Mask.By_video.(v).Distance2Boundaries;
                %close = double(app.Analysis.Channel2Mask.By_video.(v).group_close);
                %% Consider non-imaged frames in 2-color acquisitions
                % We add 1 extra columns of NaN every "ch2_rate" frames, to calculate the right D
                % if app.save_session.(v).Time_lapse == 1
                %     ch2_rate = app.save_session.(v).Frequency;
                %     non_im = app.save_session.(v).Non_imaged_frame;
                %     [h,w] = size(app.Loaded_files.video_file{1,4});
                %     extra_col = NaN(h,non_im);
                %     list = 1:ch2_rate:w;
                %     for j = 2:(length(list))
                %         c = list(1,j);
                %         v_x = [v_x(:,1:(c-1)),extra_col,v_x(:,c:end)];
                %         v_y = [v_y(:,1:(c-1)),extra_col,v_y(:,c:end)];
                %         distance = [distance(:,1:(c-1)),extra_col,distance(:,c:end)];
                %         close = [close(:,1:(c-1)),extra_col,close(:,c:end)];
                %     end
                % end
                %% Apply track filtering
                v_x(app.save_session.(v).FilteredTracks.Filter == 0,:) = NaN;
                v_y(app.save_session.(v).FilteredTracks.Filter == 0,:) = NaN;
                distance(app.save_session.(v).FilteredTracks.Filter == 0,:) = NaN;
                %close(app.save_session.(v).FilteredTracks.Filter == 0,:) = NaN;
                %% Save file for STEP
                app.Loaded_files.video_file{1,4} = v_x;
                app.Loaded_files.video_file{1,5} = v_y;
                var1 = cell(1,2);
                var1{1} = app.Loaded_files.video_file{4};
                var1{2} = app.Loaded_files.video_file{5};
                var1{3} = distance;
                %var1{4} = close;
                filename2 = path + "\" + v + ".mat";
                save(filename2,'var1','-v7.3')
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TrackingDataTab = uitab(app.TabGroup);
            app.TrackingDataTab.Title = 'Tracking Data';
            app.TrackMateDataPanel = uipanel(app.TrackingDataTab);
            app.TrackMateDataPanel.Title = 'TrackMate Data';
            app.TrackFilesListBoxLabel = uilabel(app.TrackMateDataPanel);
            app.TrackFilesListBoxLabel.Text = 'Track Files';
            app.TrackFilesListBox = uilistbox(app.TrackMateDataPanel);
            app.TrackFilesListBox.Multiselect = 'on';
            app.TrackFilesListBox.ValueChangedFcn = createCallbackFcn(app, @TrackFilesListBoxValueChanged, true);
            app.TrackFilesListBox.Value = {'Item 1'};
            app.DeleteSelectedButton = uibutton(app.TrackMateDataPanel, 'push');
            app.DeleteSelectedButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteSelectedButtonPushed, true);
            app.DeleteSelectedButton.Text = 'Delete Selected';
            app.AddButton = uibutton(app.TrackMateDataPanel, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.DeleteAllButton = uibutton(app.TrackMateDataPanel, 'push');
            app.DeleteAllButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteAllButtonPushed, true);
            app.DeleteAllButton.Text = 'Delete All';
            app.VideoFilesListBoxLabel = uilabel(app.TrackMateDataPanel);
            app.VideoFilesListBoxLabel.Text = 'Video Files';
            app.VideoFilesListBox = uilistbox(app.TrackMateDataPanel);
            app.VideoFilesListBox.Multiselect = 'on';
            app.VideoFilesListBox.ValueChangedFcn = createCallbackFcn(app, @VideoFilesListBoxValueChanged, true);
            app.VideoFilesListBox.Value = {'Item 1'};
            app.DeleteSelectedButton_2 = uibutton(app.TrackMateDataPanel, 'push');
            app.DeleteSelectedButton_2.ButtonPushedFcn = createCallbackFcn(app, @DeleteSelectedButton_2Pushed, true);
            app.DeleteSelectedButton_2.Text = 'Delete Selected';
            app.AddButton_2 = uibutton(app.TrackMateDataPanel, 'push');
            app.AddButton_2.ButtonPushedFcn = createCallbackFcn(app, @AddButton_2Pushed, true);
            app.AddButton_2.Text = 'Add';
            app.DeleteAllButton_2 = uibutton(app.TrackMateDataPanel, 'push');
            app.DeleteAllButton_2.ButtonPushedFcn = createCallbackFcn(app, @DeleteAllButton_2Pushed, true);
            app.DeleteAllButton_2.Text = 'Delete All';
            app.ImportButton = uibutton(app.TrackMateDataPanel, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.ImportPreviousSessionButton = uibutton(app.TrackingDataTab, 'push');
            app.ImportPreviousSessionButton.ButtonPushedFcn = createCallbackFcn(app, @ImportPreviousSessionButtonPushed, true);
            app.ImportPreviousSessionButton.Text = 'Import Previous Session';
            app.channelTimelapseCheckBox = uicheckbox(app.TrackingDataTab);
            app.channelTimelapseCheckBox.ValueChangedFcn = createCallbackFcn(app, @channelTimelapseCheckBoxValueChanged, true);
            app.channelTimelapseCheckBox.Text = '2-channel Time-lapse';
            app.Channel2frequencyEditFieldLabel = uilabel(app.TrackingDataTab);
            app.Channel2frequencyEditFieldLabel.Enable = 'off';
            app.Channel2frequencyEditFieldLabel.Text = 'Channel 2 frequency';
            app.Channel2frequencyEditField = uieditfield(app.TrackingDataTab, 'numeric');
            app.Channel2frequencyEditField.Limits = [1 Inf];
            app.Channel2frequencyEditField.ValueChangedFcn = createCallbackFcn(app, @Channel2frequencyEditFieldValueChanged, true);
            app.Channel2frequencyEditField.Enable = 'off';
            app.Channel2frequencyEditField.Tooltip = {'Number of frames every 2 second channel images'};
            app.Channel2frequencyEditField.Value = 1;
            app.NonimagedframesEditFieldLabel = uilabel(app.TrackingDataTab);
            app.NonimagedframesEditFieldLabel.Enable = 'off';
            app.NonimagedframesEditFieldLabel.Text = 'Non imaged frames';
            app.NonimagedframesEditField = uieditfield(app.TrackingDataTab, 'numeric');
            app.NonimagedframesEditField.Limits = [0 Inf];
            app.NonimagedframesEditField.Enable = 'off';
            app.NonimagedframesEditField.Tooltip = {'Number of '};
            app.TrackItDataPanel = uipanel(app.TrackingDataTab);
            app.TrackItDataPanel.Enable = 'off';
            app.TrackItDataPanel.Title = 'TrackIt Data';
            app.TrackFilesListBox_2Label = uilabel(app.TrackItDataPanel);
            app.TrackFilesListBox_2Label.Text = 'Track Files';
            app.TrackFilesListBox_2 = uilistbox(app.TrackItDataPanel);
            app.TrackFilesListBox_2.Multiselect = 'on';
            app.TrackFilesListBox_2.ValueChangedFcn = createCallbackFcn(app, @TrackFilesListBox_2ValueChanged, true);
            app.TrackFilesListBox_2.Value = {'Item 1'};
            app.DeleteSelectedButton_4 = uibutton(app.TrackItDataPanel, 'push');
            app.DeleteSelectedButton_4.ButtonPushedFcn = createCallbackFcn(app, @DeleteSelectedButtonPushed, true);
            app.DeleteSelectedButton_4.Text = 'Delete Selected';
            app.AddButton_3 = uibutton(app.TrackItDataPanel, 'push');
            app.AddButton_3.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton_3.Text = 'Add';
            app.DeleteAllButton_3 = uibutton(app.TrackItDataPanel, 'push');
            app.DeleteAllButton_3.ButtonPushedFcn = createCallbackFcn(app, @DeleteAllButtonPushed, true);
            app.DeleteAllButton_3.Text = 'Delete All';
            app.VideoFilesListBox_2Label = uilabel(app.TrackItDataPanel);
            app.VideoFilesListBox_2Label.Text = 'Video Files';
            app.VideoFilesListBox_2 = uilistbox(app.TrackItDataPanel);
            app.VideoFilesListBox_2.Multiselect = 'on';
            app.VideoFilesListBox_2.ValueChangedFcn = createCallbackFcn(app, @VideoFilesListBox_2ValueChanged, true);
            app.VideoFilesListBox_2.Value = {'Item 1'};
            app.DeleteSelectedButton_5 = uibutton(app.TrackItDataPanel, 'push');
            app.DeleteSelectedButton_5.ButtonPushedFcn = createCallbackFcn(app, @DeleteSelectedButton_2Pushed, true);
            app.DeleteSelectedButton_5.Text = 'Delete Selected';
            app.AddButton_4 = uibutton(app.TrackItDataPanel, 'push');
            app.AddButton_4.ButtonPushedFcn = createCallbackFcn(app, @AddButton_2Pushed, true);
            app.AddButton_4.Text = 'Add';
            app.DeleteAllButton_4 = uibutton(app.TrackItDataPanel, 'push');
            app.DeleteAllButton_4.ButtonPushedFcn = createCallbackFcn(app, @DeleteAllButton_2Pushed, true);
            app.DeleteAllButton_4.Text = 'Delete All';
            app.ImportButton_2 = uibutton(app.TrackItDataPanel, 'push');
            app.ImportButton_2.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton_2.Text = 'Import';
            app.TrackingperformedwithDropDownLabel = uilabel(app.TrackingDataTab);
            app.TrackingperformedwithDropDownLabel.Text = 'Tracking performed with';
            app.TrackingOptions = uidropdown(app.TrackingDataTab);
            app.TrackingOptions.Items = {'TrackMate', 'TrackIt'};
            app.TrackingOptions.ValueChangedFcn = createCallbackFcn(app, @TrackingOptionsValueChanged, true);
            app.TrackingOptions.Value = 'TrackMate';
            app.TrackVisualizationTab = uitab(app.TabGroup);
            app.TrackVisualizationTab.Title = 'Track Visualization';
            app.UIAxes = uiaxes(app.TrackVisualizationTab);
            app.VideoPreviewButton = uibutton(app.TrackVisualizationTab, 'push');
            app.VideoPreviewButton.ButtonPushedFcn = createCallbackFcn(app, @VideoPreviewButtonPushed, true);
            app.VideoPreviewButton.Enable = 'off';
            app.VideoPreviewButton.Text = 'Video Preview';
            app.DeleteSelectedButton_3 = uibutton(app.TrackVisualizationTab, 'push');
            app.DeleteSelectedButton_3.ButtonPushedFcn = createCallbackFcn(app, @DeleteSelectedButton_3Pushed, true);
            app.DeleteSelectedButton_3.Text = 'Delete Selected';
            app.FrameEditFieldLabel = uilabel(app.TrackVisualizationTab);
            app.FrameEditFieldLabel.Enable = 'off';
            app.FrameEditFieldLabel.Text = 'Frame';
            app.FrameEditField = uieditfield(app.TrackVisualizationTab, 'numeric');
            app.FrameEditField.Limits = [1 1000];
            app.FrameEditField.RoundFractionalValues = 'on';
            app.FrameEditField.ValueDisplayFormat = '%.0f';
            app.FrameEditField.ValueChangedFcn = createCallbackFcn(app, @FrameEditFieldValueChanged, true);
            app.FrameEditField.Enable = 'off';
            app.FrameEditField.Value = 1;
            app.VideoSelectionListBoxLabel = uilabel(app.TrackVisualizationTab);
            app.VideoSelectionListBoxLabel.Text = 'Video Selection';
            app.VideoSelectionListBox = uilistbox(app.TrackVisualizationTab);
            app.VideoSelectionListBox.ValueChangedFcn = createCallbackFcn(app, @VideoSelectionListBoxValueChanged, true);
            app.VideoSelectionListBox.Enable = 'off';
            app.DisplayPanel = uipanel(app.TrackVisualizationTab);
            app.DisplayPanel.Title = 'Display';
            app.IDnumberCheckBox = uicheckbox(app.DisplayPanel);
            app.IDnumberCheckBox.ValueChangedFcn = createCallbackFcn(app, @IDnumberCheckBoxValueChanged, true);
            app.IDnumberCheckBox.Enable = 'off';
            app.IDnumberCheckBox.Text = 'ID number';
            app.LocalizationsCheckBox = uicheckbox(app.DisplayPanel);
            app.LocalizationsCheckBox.ValueChangedFcn = createCallbackFcn(app, @LocalizationsCheckBoxValueChanged, true);
            app.LocalizationsCheckBox.Enable = 'off';
            app.LocalizationsCheckBox.Text = 'Localizations';
            app.TrajectoriesCheckBox = uicheckbox(app.DisplayPanel);
            app.TrajectoriesCheckBox.ValueChangedFcn = createCallbackFcn(app, @TrajectoriesCheckBoxValueChanged, true);
            app.TrajectoriesCheckBox.Enable = 'off';
            app.TrajectoriesCheckBox.Text = 'Trajectories';
            app.BrightFieldImageCheckBox = uicheckbox(app.DisplayPanel);
            app.BrightFieldImageCheckBox.ValueChangedFcn = createCallbackFcn(app, @BrightFieldImageCheckBoxValueChanged, true);
            app.BrightFieldImageCheckBox.Enable = 'off';
            app.BrightFieldImageCheckBox.Text = 'Bright Field Image';
            app.Channel2MaskCheckBox = uicheckbox(app.DisplayPanel);
            app.Channel2MaskCheckBox.ValueChangedFcn = createCallbackFcn(app, @Channel2MaskCheckBoxValueChanged, true);
            app.Channel2MaskCheckBox.Enable = 'off';
            app.Channel2MaskCheckBox.Text = '2nd Channel Mask';
            app.DataFilteringPanel = uipanel(app.TrackVisualizationTab);
            app.DataFilteringPanel.Title = 'Data Filtering';
            app.SelectROIButton = uibutton(app.DataFilteringPanel, 'push');
            app.SelectROIButton.ButtonPushedFcn = createCallbackFcn(app, @SelectROIButtonPushed, true);
            app.SelectROIButton.Enable = 'off';
            app.SelectROIButton.Text = 'Select ROI';
            app.EraseROIButton = uibutton(app.DataFilteringPanel, 'push');
            app.EraseROIButton.ButtonPushedFcn = createCallbackFcn(app, @EraseROIButtonPushed, true);
            app.EraseROIButton.Enable = 'off';
            app.EraseROIButton.Text = 'Erase ROI';
            app.FilterbyROICheckBox = uicheckbox(app.DataFilteringPanel);
            app.FilterbyROICheckBox.ValueChangedFcn = createCallbackFcn(app, @FilterbyROICheckBoxValueChanged, true);
            app.FilterbyROICheckBox.Enable = 'off';
            app.FilterbyROICheckBox.Text = 'Filter by ROI';
            app.FilterbytracklengthCheckBox = uicheckbox(app.DataFilteringPanel);
            app.FilterbytracklengthCheckBox.ValueChangedFcn = createCallbackFcn(app, @FilterbytracklengthCheckBoxValueChanged, true);
            app.FilterbytracklengthCheckBox.Enable = 'off';
            app.FilterbytracklengthCheckBox.Text = 'Filter by track length';
            app.ExcludeimmobilefractionCheckBox = uicheckbox(app.DataFilteringPanel);
            app.ExcludeimmobilefractionCheckBox.ValueChangedFcn = createCallbackFcn(app, @ExcludeimmobilefractionCheckBoxValueChanged, true);
            app.ExcludeimmobilefractionCheckBox.Enable = 'off';
            app.ExcludeimmobilefractionCheckBox.Text = 'Exclude immobile fraction';
            app.ExcludemobilefractionCheckBox = uicheckbox(app.DataFilteringPanel);
            app.ExcludemobilefractionCheckBox.ValueChangedFcn = createCallbackFcn(app, @ExcludemobilefractionCheckBoxValueChanged, true);
            app.ExcludemobilefractionCheckBox.Enable = 'off';
            app.ExcludemobilefractionCheckBox.Text = 'Exclude mobile fraction';
            app.OnlyspotsfromthefirstframeCheckBox = uicheckbox(app.DataFilteringPanel);
            app.OnlyspotsfromthefirstframeCheckBox.ValueChangedFcn = createCallbackFcn(app, @OnlyspotsfromthefirstframeCheckBoxValueChanged, true);
            app.OnlyspotsfromthefirstframeCheckBox.Enable = 'off';
            app.OnlyspotsfromthefirstframeCheckBox.Text = 'Only spots from the first frame';
            app.SavesessionButton = uibutton(app.TrackVisualizationTab, 'push');
            app.SavesessionButton.ButtonPushedFcn = createCallbackFcn(app, @SavesessionButtonPushed, true);
            app.SavesessionButton.Text = 'Save session';
            app.LoadforanalysisButton = uibutton(app.TrackVisualizationTab, 'push');
            app.LoadforanalysisButton.ButtonPushedFcn = createCallbackFcn(app, @LoadforanalysisButtonPushed, true);
            app.LoadforanalysisButton.Text = 'Load for analysis';
            app.Contrast2ndChannelLabel = uilabel(app.TrackVisualizationTab);
            app.Contrast2ndChannelLabel.Enable = 'off';
            app.Contrast2ndChannelLabel.Text = 'Contrast 2nd Channel';
            app.AdjustcontrastrangeButton = uibutton(app.TrackVisualizationTab, 'push');
            app.AdjustcontrastrangeButton.ButtonPushedFcn = createCallbackFcn(app, @AdjustcontrastrangeButtonPushed, true);
            app.AdjustcontrastrangeButton.Enable = 'off';
            app.AdjustcontrastrangeButton.Text = 'Adjust contrast range';
            app.ResetcontrastButton = uibutton(app.TrackVisualizationTab, 'push');
            app.ResetcontrastButton.ButtonPushedFcn = createCallbackFcn(app, @ResetcontrastButtonPushed, true);
            app.ResetcontrastButton.Enable = 'off';
            app.ResetcontrastButton.Text = 'Reset contrast';
            app.LoadPreviousButton = uibutton(app.TrackVisualizationTab, 'push');
            app.LoadPreviousButton.ButtonPushedFcn = createCallbackFcn(app, @LoadPreviousButtonPushed, true);
            app.LoadPreviousButton.Enable = 'off';
            app.LoadPreviousButton.Text = 'Load Previous';
            app.DisplayedvideoTextAreaLabel = uilabel(app.TrackVisualizationTab);
            app.DisplayedvideoTextAreaLabel.Enable = 'off';
            app.DisplayedvideoTextAreaLabel.Text = 'Displayed video';
            app.DisplayedvideoTextArea = uitextarea(app.TrackVisualizationTab);
            app.DisplayedvideoTextArea.Editable = 'off';
            app.DisplayedvideoTextArea.Enable = 'off';
            app.AdjustcontrastrangeButton_ch2 = uibutton(app.TrackVisualizationTab, 'push');
            app.AdjustcontrastrangeButton_ch2.ButtonPushedFcn = createCallbackFcn(app, @AdjustcontrastrangeButton_ch2Pushed, true);
            app.AdjustcontrastrangeButton_ch2.Enable = 'off';
            app.AdjustcontrastrangeButton_ch2.Text = 'Adjust contrast range';
            app.ResetcontrastButton_ch2 = uibutton(app.TrackVisualizationTab, 'push');
            app.ResetcontrastButton_ch2.ButtonPushedFcn = createCallbackFcn(app, @ResetcontrastButton_ch2Pushed, true);
            app.ResetcontrastButton_ch2.Enable = 'off';
            app.ResetcontrastButton_ch2.Text = 'Reset contrast';
            app.Contrast1stChannelLabel = uilabel(app.TrackVisualizationTab);
            app.Contrast1stChannelLabel.Enable = 'off';
            app.Contrast1stChannelLabel.Text = 'Contrast 1st Channel';
            app.AnalysisTab = uitab(app.TabGroup);
            app.AnalysisTab.Title = 'Analysis';
            app.GeneralFiltersPanel = uipanel(app.AnalysisTab);
            app.GeneralFiltersPanel.Title = 'General Filters';
            app.FilterbytracklengthCheckBox_2 = uicheckbox(app.GeneralFiltersPanel);
            app.FilterbytracklengthCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @FilterbytracklengthCheckBox_2ValueChanged, true);
            app.FilterbytracklengthCheckBox_2.Enable = 'off';
            app.FilterbytracklengthCheckBox_2.Text = 'Filter by track length';
            app.ExcludemobilefractionCheckBox_2 = uicheckbox(app.GeneralFiltersPanel);
            app.ExcludemobilefractionCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @ExcludemobilefractionCheckBox_2ValueChanged, true);
            app.ExcludemobilefractionCheckBox_2.Enable = 'off';
            app.ExcludemobilefractionCheckBox_2.Text = 'Exclude mobile fraction';
            app.EnablefilteringbyROICheckBox_2 = uicheckbox(app.GeneralFiltersPanel);
            app.EnablefilteringbyROICheckBox_2.ValueChangedFcn = createCallbackFcn(app, @EnablefilteringbyROICheckBox_2ValueChanged, true);
            app.EnablefilteringbyROICheckBox_2.Enable = 'off';
            app.EnablefilteringbyROICheckBox_2.Text = 'Enable filtering by ROI';
            app.ExcludeimmobilefractionCheckBox_2 = uicheckbox(app.GeneralFiltersPanel);
            app.ExcludeimmobilefractionCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @ExcludeimmobilefractionCheckBox_2ValueChanged, true);
            app.ExcludeimmobilefractionCheckBox_2.Enable = 'off';
            app.ExcludeimmobilefractionCheckBox_2.Text = 'Exclude immobile fraction';
            app.OnlyspotsfromthefirstframeCheckBox_2 = uicheckbox(app.GeneralFiltersPanel);
            app.OnlyspotsfromthefirstframeCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @OnlyspotsfromthefirstframeCheckBox_2ValueChanged, true);
            app.OnlyspotsfromthefirstframeCheckBox_2.Enable = 'off';
            app.OnlyspotsfromthefirstframeCheckBox_2.Text = 'Only spots from the first frame';
            app.DiffusionAnalysisPanel = uipanel(app.AnalysisTab);
            app.DiffusionAnalysisPanel.Title = 'Diffusion Analysis';
            app.ImmediateDiffusionCoefficientButton = uibutton(app.DiffusionAnalysisPanel, 'push');
            app.ImmediateDiffusionCoefficientButton.ButtonPushedFcn = createCallbackFcn(app, @ImmediateDiffusionCoefficientButtonPushed, true);
            app.ImmediateDiffusionCoefficientButton.Enable = 'off';
            app.ImmediateDiffusionCoefficientButton.Text = 'Immediate Diffusion Coefficient';
            app.MomentScalingSpectrumButton = uibutton(app.DiffusionAnalysisPanel, 'push');
            app.MomentScalingSpectrumButton.ButtonPushedFcn = createCallbackFcn(app, @MomentScalingSpectrumButtonPushed, true);
            app.MomentScalingSpectrumButton.Enable = 'off';
            app.MomentScalingSpectrumButton.Text = 'Moment Scaling Spectrum';
            app.InstantvelocityButton = uibutton(app.DiffusionAnalysisPanel, 'push');
            app.InstantvelocityButton.ButtonPushedFcn = createCallbackFcn(app, @InstantvelocityButtonPushed, true);
            app.InstantvelocityButton.Enable = 'off';
            app.InstantvelocityButton.Text = 'Instant velocity';
            app.TransientConfinementZonesButton = uibutton(app.DiffusionAnalysisPanel, 'push');
            app.TransientConfinementZonesButton.ButtonPushedFcn = createCallbackFcn(app, @TransientConfinementZonesButtonPushed, true);
            app.TransientConfinementZonesButton.Enable = 'off';
            app.TransientConfinementZonesButton.Text = 'Transient Confinement Zones';
            app.TurningAngleButton = uibutton(app.DiffusionAnalysisPanel, 'push');
            app.TurningAngleButton.ButtonPushedFcn = createCallbackFcn(app, @TurningAngleButtonPushed, true);
            app.TurningAngleButton.Enable = 'off';
            app.TurningAngleButton.Text = 'Turning Angle';
            app.DiffusionradiusButton = uibutton(app.DiffusionAnalysisPanel, 'push');
            app.DiffusionradiusButton.ButtonPushedFcn = createCallbackFcn(app, @DiffusionradiusButtonPushed, true);
            app.DiffusionradiusButton.Enable = 'off';
            app.DiffusionradiusButton.Text = 'Diffusion radius';
            app.GeneralizedDiffusionCoefficientButton = uibutton(app.DiffusionAnalysisPanel, 'push');
            app.GeneralizedDiffusionCoefficientButton.ButtonPushedFcn = createCallbackFcn(app, @GeneralizedDiffusionCoefficientButtonPushed, true);
            app.GeneralizedDiffusionCoefficientButton.Enable = 'off';
            app.GeneralizedDiffusionCoefficientButton.Text = 'Generalized Diffusion Coefficient';
            app.SampleCalibrationPanel = uipanel(app.AnalysisTab);
            app.SampleCalibrationPanel.Title = 'Sample Calibration';
            app.PhotobleachingStepsButton = uibutton(app.SampleCalibrationPanel, 'push');
            app.PhotobleachingStepsButton.ButtonPushedFcn = createCallbackFcn(app, @PhotobleachingStepsButtonPushed, true);
            app.PhotobleachingStepsButton.Enable = 'off';
            app.PhotobleachingStepsButton.Text = 'Photobleaching Steps';
            app.LocalizationPrecisionButton = uibutton(app.SampleCalibrationPanel, 'push');
            app.LocalizationPrecisionButton.ButtonPushedFcn = createCallbackFcn(app, @LocalizationPrecisionButtonPushed, true);
            app.LocalizationPrecisionButton.Enable = 'off';
            app.LocalizationPrecisionButton.Text = 'Localization Precision';
            app.TrajectorylengthButton = uibutton(app.SampleCalibrationPanel, 'push');
            app.TrajectorylengthButton.ButtonPushedFcn = createCallbackFcn(app, @TrajectorylengthButtonPushed, true);
            app.TrajectorylengthButton.Text = 'Trajectory length';
            app.DistanceaftergapButton = uibutton(app.SampleCalibrationPanel, 'push');
            app.DistanceaftergapButton.ButtonPushedFcn = createCallbackFcn(app, @DistanceaftergapButtonPushed, true);
            app.DistanceaftergapButton.Text = 'Distance after gap';
            app.SpotSNRButton = uibutton(app.SampleCalibrationPanel, 'push');
            app.SpotSNRButton.ButtonPushedFcn = createCallbackFcn(app, @SpotSNRButtonPushed, true);
            app.SpotSNRButton.Text = 'Spot SNR';
            app.SpotradiusSpinnerLabel = uilabel(app.SampleCalibrationPanel);
            app.SpotradiusSpinnerLabel.Text = 'Spot radius';
            app.SpotradiusSpinner = uispinner(app.SampleCalibrationPanel);
            app.SpotradiusSpinner.Limits = [2 10];
            app.SpotradiusSpinner.Value = 5;
            app.FramerateanalysisButton = uibutton(app.SampleCalibrationPanel, 'push');
            app.FramerateanalysisButton.ButtonPushedFcn = createCallbackFcn(app, @FramerateanalysisButtonPushed, true);
            app.FramerateanalysisButton.Text = 'Frame rate analysis';
            app.ndChannelMaskAnalysisPanel = uipanel(app.AnalysisTab);
            app.ndChannelMaskAnalysisPanel.Enable = 'off';
            app.ndChannelMaskAnalysisPanel.Title = '2nd Channel Mask Analysis';
            app.UIAxes_2 = uiaxes(app.ndChannelMaskAnalysisPanel);
            app.videoselection = uispinner(app.ndChannelMaskAnalysisPanel);
            app.videoselection.Limits = [1 2];
            app.videoselection.ValueChangedFcn = createCallbackFcn(app, @videoselectionValueChanged, true);
            app.videoselection.Enable = 'off';
            app.videoselection.Value = 1;
            app.videoname = uieditfield(app.ndChannelMaskAnalysisPanel, 'text');
            app.videoname.Editable = 'off';
            app.videoname.Enable = 'off';
            app.FrameSliderLabel = uilabel(app.ndChannelMaskAnalysisPanel);
            app.FrameSliderLabel.Enable = 'off';
            app.FrameSliderLabel.Text = 'Frame';
            app.FrameSlider_Ch2 = uislider(app.ndChannelMaskAnalysisPanel);
            app.FrameSlider_Ch2.ValueChangingFcn = createCallbackFcn(app, @FrameSlider_Ch2ValueChanging, true);
            app.FrameSlider_Ch2.Enable = 'off';
            app.ShowlocalizationsCheckBox = uicheckbox(app.ndChannelMaskAnalysisPanel);
            app.ShowlocalizationsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowlocalizationsCheckBoxValueChanged, true);
            app.ShowlocalizationsCheckBox.Enable = 'off';
            app.ShowlocalizationsCheckBox.Text = 'Show localizations';
            app.AnalyzethisvideoButton = uibutton(app.ndChannelMaskAnalysisPanel, 'push');
            app.AnalyzethisvideoButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzethisvideoButtonPushed, true);
            app.AnalyzethisvideoButton.Enable = 'off';
            app.AnalyzethisvideoButton.Text = 'Analyze this video';
            app.ResetThresholdButton = uibutton(app.ndChannelMaskAnalysisPanel, 'push');
            app.ResetThresholdButton.ButtonPushedFcn = createCallbackFcn(app, @ResetThresholdButtonPushed, true);
            app.ResetThresholdButton.Enable = 'off';
            app.ResetThresholdButton.Text = 'Reset Threshold';
            app.AnalyzeallvideosButton = uibutton(app.ndChannelMaskAnalysisPanel, 'push');
            app.AnalyzeallvideosButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeallvideosButtonPushed, true);
            app.AnalyzeallvideosButton.Enable = 'off';
            app.AnalyzeallvideosButton.Text = 'Analyze all videos';
            app.IntensityThrSpinnerLabel = uilabel(app.ndChannelMaskAnalysisPanel);
            app.IntensityThrSpinnerLabel.Enable = 'off';
            app.IntensityThrSpinnerLabel.Text = 'Intensity Thr';
            app.IntensityThrSpinner = uispinner(app.ndChannelMaskAnalysisPanel);
            app.IntensityThrSpinner.Limits = [1 5];
            app.IntensityThrSpinner.ValueChangedFcn = createCallbackFcn(app, @IntensityThrSpinnerValueChanged, true);
            app.IntensityThrSpinner.Enable = 'off';
            app.IntensityThrSpinner.Value = 3;
            app.RegionAnalysisSwitch = uiswitch(app.ndChannelMaskAnalysisPanel, 'toggle');
            app.RegionAnalysisSwitch.Items = {'Simple', 'Complex'};
            app.RegionAnalysisSwitch.ValueChangedFcn = createCallbackFcn(app, @RegionAnalysisSwitchValueChanged, true);
            app.RegionAnalysisSwitch.Enable = 'off';
            app.RegionAnalysisSwitch.Value = 'Simple';
            app.AnalysisTypeDropDownLabel = uilabel(app.ndChannelMaskAnalysisPanel);
            app.AnalysisTypeDropDownLabel.Enable = 'off';
            app.AnalysisTypeDropDownLabel.Text = 'Analysis Type';
            app.AnalysisTypeDropDown = uidropdown(app.ndChannelMaskAnalysisPanel);
            app.AnalysisTypeDropDown.Items = {'In vs. Out', 'Close vs. Far', 'Distance of last frame'};
            app.AnalysisTypeDropDown.Enable = 'off';
            app.AnalysisTypeDropDown.Value = 'In vs. Out';
            app.ImporttrajectoriesforSTEPButton = uibutton(app.AnalysisTab, 'push');
            app.ImporttrajectoriesforSTEPButton.ButtonPushedFcn = createCallbackFcn(app, @Import4STEP, true);
            app.ImporttrajectoriesforSTEPButton.Text = 'Import trajectories for STEP';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SPT_GUI_v9

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end